classdef fNIRSapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ninjaGUI20000betaUIFigure       matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        menuLoadSDG                     matlab.ui.container.Menu
        menuSaveSDG                     matlab.ui.container.Menu
        menuSaveData                    matlab.ui.container.Menu
        menuExit                        matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        menu_AutoSave                   matlab.ui.container.Menu
        menu_AutoSaveDirectory          matlab.ui.container.Menu
        menu_DataCollectionPeriod       matlab.ui.container.Menu
        menu_DataCollection15min        matlab.ui.container.Menu
        menu_DataCollection30min        matlab.ui.container.Menu
        menu_DataCollection60min        matlab.ui.container.Menu
        menu_DataCollection120min       matlab.ui.container.Menu
        menu_DataCollection180min       matlab.ui.container.Menu
        menuSDGdisplaySignalLevel       matlab.ui.container.Menu
        menuSDGdisplaySignalLevel_Threshold  matlab.ui.container.Menu
        menuSDGdisplaySignalLevel_Analog  matlab.ui.container.Menu
        DatastreamingMenu               matlab.ui.container.Menu
        menuStartKeypress               matlab.ui.container.Menu
        menuStateSwitching              matlab.ui.container.Menu
        menuLaserLinearityCalibration   matlab.ui.container.Menu
        menuSystemCharacterization      matlab.ui.container.Menu
        LowpassvisualizationMenu        matlab.ui.container.Menu
        SDGdisplaysignallevelMenu       matlab.ui.container.Menu
        DarkcalibrationMenu             matlab.ui.container.Menu
        AcquiredarkMenu                 matlab.ui.container.Menu
        SubtractdarkMenu                matlab.ui.container.Menu
        CalibrationGUIMenu              matlab.ui.container.Menu
        ShowoptodeasnumbersCheckBox     matlab.ui.control.CheckBox
        displayCheckBox                 matlab.ui.control.CheckBox
        editRate                        matlab.ui.control.NumericEditField
        RateEditFieldLabel              matlab.ui.control.Label
        DebugButton                     matlab.ui.control.Button
        textSDGfilename                 matlab.ui.control.Label
        textMessage                     matlab.ui.control.Label
        pushbuttonAutoGainSrcDet        matlab.ui.control.Button
        editAutoGainSrcDet              matlab.ui.control.EditField
        textAutoGainSrcDet              matlab.ui.control.Label
        pushbuttonStimMark              matlab.ui.control.Button
        togglebuttonSystemCharTime      matlab.ui.control.StateButton
        uipanelAux                      matlab.ui.container.Panel
        togglebuttonDisplayAux          matlab.ui.control.StateButton
        popupmenuAux1                   matlab.ui.control.DropDown
        popupmenuAux2                   matlab.ui.control.DropDown
        popupmenuAux3                   matlab.ui.control.DropDown
        popupmenuAux4                   matlab.ui.control.DropDown
        popupmenuAux5                   matlab.ui.control.DropDown
        popupmenuAux6                   matlab.ui.control.DropDown
        popupmenuAux7                   matlab.ui.control.DropDown
        popupmenuAux8                   matlab.ui.control.DropDown
        checkboxAux8                    matlab.ui.control.CheckBox
        checkboxAux7                    matlab.ui.control.CheckBox
        checkboxAux6                    matlab.ui.control.CheckBox
        checkboxAux5                    matlab.ui.control.CheckBox
        checkboxAux4                    matlab.ui.control.CheckBox
        checkboxAux3                    matlab.ui.control.CheckBox
        checkboxAux2                    matlab.ui.control.CheckBox
        checkboxAux1                    matlab.ui.control.CheckBox
        uipanel5                        matlab.ui.container.Panel
        DisplaytypeButtonGroup          matlab.ui.container.ButtonGroup
        HemoglobinButton                matlab.ui.control.RadioButton
        DeltaODButton                   matlab.ui.control.RadioButton
        dBButton                        matlab.ui.control.RadioButton
        checkboxAutoscale               matlab.ui.control.CheckBox
        ymaxEditField                   matlab.ui.control.NumericEditField
        ymaxEditFieldLabel              matlab.ui.control.Label
        yminEditField                   matlab.ui.control.NumericEditField
        yminEditFieldLabel              matlab.ui.control.Label
        DisplayWindowsecondsEditField   matlab.ui.control.NumericEditField
        DisplayWindowsecondsEditFieldLabel  matlab.ui.control.Label
        WavelengthdisplayListBox        matlab.ui.control.ListBox
        WavelengthdisplayListBoxLabel   matlab.ui.control.Label
        uipanelStateSwitching           matlab.ui.container.ButtonGroup
        pushbuttonLoadStates            matlab.ui.control.Button
        textSrcSwitchDwell              matlab.ui.control.Label
        editSrcSwitchDwell3             matlab.ui.control.EditField
        editSrcSwitchDwell2             matlab.ui.control.EditField
        editSrcSwitchDwell1             matlab.ui.control.EditField
        radiobuttonState2               matlab.ui.control.RadioButton
        radiobuttonState1               matlab.ui.control.RadioButton
        radiobuttonState3               matlab.ui.control.RadioButton
        checkboxSrcSwitchEnable         matlab.ui.control.CheckBox
        togglebuttonReset               matlab.ui.control.Button
        togglebuttonSystemCharacterize  matlab.ui.control.StateButton
        uipanelDetectors                matlab.ui.container.Panel
        pushbuttonCheckSaturation       matlab.ui.control.Button
        pushbuttonSetGain               matlab.ui.control.Button
        editAutoGainLevel               matlab.ui.control.EditField
        textSetGain                     matlab.ui.control.Label
        editGain24                      matlab.ui.control.EditField
        editGain32                      matlab.ui.control.EditField
        editGain31                      matlab.ui.control.EditField
        editGain30                      matlab.ui.control.EditField
        editGain29                      matlab.ui.control.EditField
        editGain28                      matlab.ui.control.EditField
        editGain27                      matlab.ui.control.EditField
        editGain26                      matlab.ui.control.EditField
        editGain25                      matlab.ui.control.EditField
        textDet32                       matlab.ui.control.Label
        textDet31                       matlab.ui.control.Label
        textDet30                       matlab.ui.control.Label
        textDet29                       matlab.ui.control.Label
        textDet28                       matlab.ui.control.Label
        textDet27                       matlab.ui.control.Label
        textDet26                       matlab.ui.control.Label
        textDet25                       matlab.ui.control.Label
        editGain23                      matlab.ui.control.EditField
        editGain22                      matlab.ui.control.EditField
        editGain21                      matlab.ui.control.EditField
        editGain20                      matlab.ui.control.EditField
        editGain19                      matlab.ui.control.EditField
        editGain18                      matlab.ui.control.EditField
        editGain17                      matlab.ui.control.EditField
        textDet24                       matlab.ui.control.Label
        textDet23                       matlab.ui.control.Label
        textDet22                       matlab.ui.control.Label
        textDet21                       matlab.ui.control.Label
        textDet20                       matlab.ui.control.Label
        textDet19                       matlab.ui.control.Label
        textDet18                       matlab.ui.control.Label
        textDet17                       matlab.ui.control.Label
        editGain8                       matlab.ui.control.EditField
        editGain16                      matlab.ui.control.EditField
        editGain15                      matlab.ui.control.EditField
        editGain14                      matlab.ui.control.EditField
        editGain13                      matlab.ui.control.EditField
        editGain12                      matlab.ui.control.EditField
        editGain11                      matlab.ui.control.EditField
        editGain10                      matlab.ui.control.EditField
        editGain9                       matlab.ui.control.EditField
        textDet16                       matlab.ui.control.Label
        textDet15                       matlab.ui.control.Label
        textDet14                       matlab.ui.control.Label
        textDet13                       matlab.ui.control.Label
        textDet12                       matlab.ui.control.Label
        textDet11                       matlab.ui.control.Label
        textDet10                       matlab.ui.control.Label
        textDet9                        matlab.ui.control.Label
        editGain7                       matlab.ui.control.EditField
        editGain6                       matlab.ui.control.EditField
        editGain5                       matlab.ui.control.EditField
        editGain4                       matlab.ui.control.EditField
        editGain3                       matlab.ui.control.EditField
        editGain2                       matlab.ui.control.EditField
        editGain1                       matlab.ui.control.EditField
        textDet8                        matlab.ui.control.Label
        textDet7                        matlab.ui.control.Label
        textDet6                        matlab.ui.control.Label
        textDet5                        matlab.ui.control.Label
        textDet4                        matlab.ui.control.Label
        textDet3                        matlab.ui.control.Label
        textDet2                        matlab.ui.control.Label
        textDet1                        matlab.ui.control.Label
        textGainAll                     matlab.ui.control.Label
        editGainAll                     matlab.ui.control.EditField
        uipanelSources                  matlab.ui.container.Panel
        Button_16                       matlab.ui.control.StateButton
        Button_15                       matlab.ui.control.StateButton
        Button_14                       matlab.ui.control.StateButton
        Button_13                       matlab.ui.control.StateButton
        Button_12                       matlab.ui.control.StateButton
        Button_11                       matlab.ui.control.StateButton
        Button_10                       matlab.ui.control.StateButton
        Button_9                        matlab.ui.control.StateButton
        Button_8                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button_1                        matlab.ui.control.StateButton
        textSetSrcPowerAll              matlab.ui.control.Label
        editSetSrcPowerAll              matlab.ui.control.EditField
        pushbuttonSetSrcPower           matlab.ui.control.Button
        editSetSrcPower                 matlab.ui.control.EditField
        textSetSrcPower                 matlab.ui.control.Label
        editSrcPower32                  matlab.ui.control.EditField
        editSrcPower30                  matlab.ui.control.EditField
        editSrcPower28                  matlab.ui.control.EditField
        editSrcPower26                  matlab.ui.control.EditField
        editSrcPower24                  matlab.ui.control.EditField
        editSrcPower22                  matlab.ui.control.EditField
        editSrcPower20                  matlab.ui.control.EditField
        editSrcPower18                  matlab.ui.control.EditField
        editSrcPower31                  matlab.ui.control.EditField
        editSrcPower29                  matlab.ui.control.EditField
        editSrcPower27                  matlab.ui.control.EditField
        editSrcPower25                  matlab.ui.control.EditField
        editSrcPower23                  matlab.ui.control.EditField
        editSrcPower21                  matlab.ui.control.EditField
        editSrcPower19                  matlab.ui.control.EditField
        editSrcPower17                  matlab.ui.control.EditField
        editSrcPower16                  matlab.ui.control.EditField
        editSrcPower14                  matlab.ui.control.EditField
        editSrcPower12                  matlab.ui.control.EditField
        editSrcPower10                  matlab.ui.control.EditField
        editSrcPower8                   matlab.ui.control.EditField
        editSrcPower6                   matlab.ui.control.EditField
        editSrcPower4                   matlab.ui.control.EditField
        editSrcPower2                   matlab.ui.control.EditField
        editSrcPower15                  matlab.ui.control.EditField
        editSrcPower13                  matlab.ui.control.EditField
        editSrcPower11                  matlab.ui.control.EditField
        editSrcPower9                   matlab.ui.control.EditField
        editSrcPower7                   matlab.ui.control.EditField
        editSrcPower5                   matlab.ui.control.EditField
        editSrcPower3                   matlab.ui.control.EditField
        editSrcPower1                   matlab.ui.control.EditField
        togglebuttonSrcOnOff            matlab.ui.control.StateButton
        togglebuttonSrc12_2             matlab.ui.control.StateButton
        togglebuttonSrc12_1             matlab.ui.control.StateButton
        togglebuttonSrc11_2             matlab.ui.control.StateButton
        togglebuttonSrc11_1             matlab.ui.control.StateButton
        togglebuttonSrc10_2             matlab.ui.control.StateButton
        togglebuttonSrc10_1             matlab.ui.control.StateButton
        togglebuttonSrc9_2              matlab.ui.control.StateButton
        togglebuttonSrc9_1              matlab.ui.control.StateButton
        togglebuttonSrc16_2             matlab.ui.control.StateButton
        togglebuttonSrc16_1             matlab.ui.control.StateButton
        togglebuttonSrc15_2             matlab.ui.control.StateButton
        togglebuttonSrc15_1             matlab.ui.control.StateButton
        togglebuttonSrc14_2             matlab.ui.control.StateButton
        togglebuttonSrc14_1             matlab.ui.control.StateButton
        togglebuttonSrc13_2             matlab.ui.control.StateButton
        togglebuttonSrc13_1             matlab.ui.control.StateButton
        togglebuttonSrc4_2              matlab.ui.control.StateButton
        togglebuttonSrc4_1              matlab.ui.control.StateButton
        togglebuttonSrc3_2              matlab.ui.control.StateButton
        togglebuttonSrc3_1              matlab.ui.control.StateButton
        togglebuttonSrc2_2              matlab.ui.control.StateButton
        togglebuttonSrc2_1              matlab.ui.control.StateButton
        togglebuttonSrc1_2              matlab.ui.control.StateButton
        togglebuttonSrc1_1              matlab.ui.control.StateButton
        togglebuttonSrc8_2              matlab.ui.control.StateButton
        togglebuttonSrc8_1              matlab.ui.control.StateButton
        togglebuttonSrc7_2              matlab.ui.control.StateButton
        togglebuttonSrc7_1              matlab.ui.control.StateButton
        togglebuttonSrc6_2              matlab.ui.control.StateButton
        togglebuttonSrc6_1              matlab.ui.control.StateButton
        togglebuttonSrc5_2              matlab.ui.control.StateButton
        togglebuttonSrc5_1              matlab.ui.control.StateButton
        textNoiseThresh                 matlab.ui.control.Label
        editStdThresh                   matlab.ui.control.EditField
        togglebuttonStartStop           matlab.ui.control.StateButton
        axesSDG                         matlab.ui.control.UIAxes
        axisPlotData                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        LSLstreamer  %LSL streamer
        sp % Serial port object
        deviceInformation=[] % Contains all relevant information of the hardware system
        deviceFunctions% contains the function set for the device
        Hardware % String storing the hardware type
        Port    % String storing the port
        HWdialstate % Description
        DevType %String identifyin the device type
        nSD% Contents of the probe file
        LEDstate=[]; % used to save the current state of each source
        active=0; % Boolean to specify if the app is currently doing acquisition
        fstreamID=[]; % fID to stream received bytes to file (debugging)
        rbytes=[]; % Stores unused read bytes from incomplete packages
        communicationParameters% Contains the serial port parameters
        optode_text_handles% Contains optode text handles
        optode_dot_handles% Contains optode dot handles
    end

    properties (Access = private)
        currchans % Current channels to display on plot; it's an array collecting the rows of the measurelist we wish to plot
        databuffer % Stores the acquired formatted data
        time % Stores the time vector (for real-time visualization)
        currt % Current time relative to ACQ start; used for visualization purposes
        stims % Vector to save stimmarks
        Level % stores the desired "on" power level for all sources 
        colores % defines the colors used by the app for plotting
        lineS % defines the linestyles used by the app for plotting
        nMins=120; % Default number of minutes to record; should be changed by menu
        streaming=0 % Determines if streaming is enabled
        plotAux % Flag to determine if plotting aux or data 0: data, 1: aux
        auxDisp=ones(1,8) % Flags to determine which aux channels to display. 8 element array, first element (leftmost) is first Aux and so on
        triggersfinal % Auxiliary variable to store triggers
        debugMode=1; % Determines if the app is in debug mode
        AutoSaveFolderPath=[]; % Folder path for autosave function
        butterFilter=[] % Stores Butterworth filter parameters
        SD=[]; % SD file descriptor for compatibility
        levelRepresentation % stores properties related to the way the channel intensity is represented in color
    end


    methods (Access = private)


        function results = drawSD(app)
            %Function to draw the source/detector diagram

            results=1; %use to indicate success or something
            nSD=app.nSD; %#ok<ADPROP>
            Nchans=size(nSD.measList,1)/nSD.nLambdas; %#ok<ADPROP> %number of channels

            SrcPos=nSD.srcPos; %#ok<ADPROP>
            DetPos=nSD.detPos; %#ok<ADPROP>
            nSrcs=nSD.nSrcs; %#ok<ADPROP>
            nDets=nSD.nDets; %#ok<ADPROP>
            ax=app.axesSDG;
%             ax.Toolbar.Visible = 'on';

            %empty axis
            cla(ax)
            set(ax,'box','off')
            set(ax,'xtick',[])
            set(ax,'xticklabel',[])
            set(ax,'ytick',[])
            set(ax,'yticklabel',[])
            set(ax,'xcolor','none')
            set(ax,'ycolor','none')
%             set(ax,'YDir','reverse')

            hold(ax,'on')

            % Find canvas size
            rangex = max(max(SrcPos(:,1),max(DetPos(:,1)))) - min(min(SrcPos(:,1),min(DetPos(:,1))))+.1;
            rangey = max(max(SrcPos(:,2),max(DetPos(:,2)))) - min(min(SrcPos(:,2),min(DetPos(:,2))))+.1;

            %these should be set according to "canvas size"
            lw=7; %linewidth of channel will be 10-lw
            ms=16; %marker size
            dn=rangex/20;  %displacement for source/detector numbers
            wn=rangex/25;  %Expected number width
            hn=rangey/14;  %Expected number half-height
            fs=12;  %font size for sd numbers

            %draw channels to be used for signal intensity representation
            ML=nSD.measList; %#ok<ADPROP>
            for k=1:Nchans
                x=[SrcPos(ML(k,1),1),DetPos(ML(k,2),1)];
                y=[SrcPos(ML(k,1),2),DetPos(ML(k,2),2)];
                line(ax,x,y,'Color',app.levelRepresentation.color.notAnumber,'LineWidth',10,...
                    'Tag',['Ind',num2str(k)],...
                    'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
            end

            %draw channels as grey lines
            ML=nSD.measList; %#ok<ADPROP>
            for k=1:Nchans
                x=[SrcPos(ML(k,1),1),DetPos(ML(k,2),1)];
                y=[SrcPos(ML(k,1),2),DetPos(ML(k,2),2)];
                line(ax,x,y,'Color',[0.7,0.7,0.7],'LineWidth',10-lw,...
                    'Tag',['Cha',num2str(k)],...
                    'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
            end

            %Decide the visibility of each source and detector; the
            %idea is to make the inactive ones invisible
            flags=cell(1,2);
            flags{1,1}=ones(1,nSrcs);
            flags{1,2}=ones(1,nDets);
            flags{1,1}(setdiff(1:nSrcs,ML(:,1)'))=0;
            flags{1,2}(setdiff(1:nDets,ML(:,2)'))=0;

            % store text and dot handles
            app.optode_text_handles = gobjects(nSrcs+nDets);
            app.optode_dot_handles = gobjects(nSrcs+nDets);
            %draw sources
            for k=1:nSrcs
                if flags{1,1}(k)
                    h =plot(ax,SrcPos(k,1),SrcPos(k,2),'r.','MarkerSize',ms,...
                            'Tag',['Src',num2str(k)],...
                            'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
                    app.optode_dot_handles(k) = h;
                    h = text(ax,SrcPos(k,1),...
                        SrcPos(k,2),num2str(k),...
                        'Color',[1,0,0],'FontSize',fs,...
                        'Tag',['Src',num2str(k)],...
                        'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
                        set(h,'Clipping', 'on');
                     app.optode_text_handles(k) = h;
                    if ~app.ShowoptodeasnumbersCheckBox.Value
                       set(app.optode_text_handles(k),'Visible','off')
                    end
                end
            end

            %draw detectors
            for k=1:nDets
                if flags{1,2}(k)
                   h = plot(ax,DetPos(k,1),DetPos(k,2),'b.','MarkerSize',ms,...
                        'Tag',['Det',num2str(k)],...
                        'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
                   app.optode_dot_handles(nSrcs+k) = h; 
                    h = text(ax,DetPos(k,1),...
                    DetPos(k,2),num2str(k),...
                    'Color',[0,0,1],'FontSize',fs,...
                    'Tag',['Det',num2str(k)],...
                    'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
                    set(h,'Clipping', 'on');
                    app.optode_text_handles(nSrcs+k) = h; 
                    if ~app.ShowoptodeasnumbersCheckBox.Value
                        set(app.optode_text_handles(nSrcs+k),'Visible','off')
                    end
                end
            end

            % set the limits of the display area - 12% leeway given on each side
            minX = min(min(SrcPos(:,1),min(DetPos(:,1)))) - rangex*0.12;
            maxX = max(max(SrcPos(:,1),max(DetPos(:,1)))) + rangex*0.12;
            minY = min(min(SrcPos(:,2),min(DetPos(:,2)))) - rangey*0.12;
            maxY = max(max(SrcPos(:,2),max(DetPos(:,2)))) + rangey*0.12;

            axis(ax, [minX, maxX, minY, maxY])

            %disable standard interactions
            disableDefaultInteractivity(ax)
            ax.Toolbar.Visible='on';


%             if isfield(app,'event')
%                 SDGcallback(app.event);
%             end

            %draw numbers
%             DrawNumbers(app, hn, dn, wn);

        end

        function result = SetupForm(app)
            %% This function updates the form's controls based on the hardware
            result=0;
            dev=app.deviceInformation;
            SD1=app.nSD;

            %identify wavelengths
            lambdas=cellstr(string(dev.Wavelengths));
            app.WavelengthdisplayListBox.Items=lambdas;
            app.WavelengthdisplayListBox.Value=lambdas;

            %% decide if source power visible

            %% state controls
            % right now make state panel invisible
            app.uipanelStateSwitching.Visible=0;
            app.togglebuttonSystemCharTime.Visible=0;
            app.togglebuttonReset.Visible=0;

            %% decide if rate is adjustable
            if dev.AdjustableRate
                app.editRate.Enable=1;
            else
                app.editRate.Enable=0;
            end
            app.editRate.Value=dev.Rate;

            %% decide if gain visible
            if dev.AdjustableGain
                for k=1:32
                    app.(sprintf('editGain%i',k)).Visible=1;
                end
            else
                for k=1:32
                    app.(sprintf('editGain%i',k)).Visible=0;
                end
                app.editGainAll.Visible=0;
                app.textGainAll.Visible=0;
                app.textSetGain.Visible=0;
                app.editAutoGainLevel.Visible=0;
                app.pushbuttonSetGain.Visible=0;
                app.textAutoGainSrcDet.Visible=0;
                app.editAutoGainSrcDet.Visible=0;
                app.pushbuttonAutoGainSrcDet.Visible=0;
            end

            %% show or display auxiliaries and set their names
            nAux=dev.nAux;


            if ~nAux  %if one or more Aux channels exist in the hardware, make Aux panel and button visible
                app.uipanelAux.Visible=0;
                app.togglebuttonDisplayAux.Visible=0;

                % for the following code, I will assume that the Auxlist
                % contains a name for all the Aux channels of interest;
                % that is, if we have nAux channels but
                % length(Auxlist)<nAux, then we are not interested in all
                % hardware channels, just the ones in the Auxlist. So, if
                % the Auxlist is empty, I assume we don't care about the
                % auxiliaries. Also, the GUI is only compatible with up to
                % 8 aux channels
            else
                for k=1:8
                    app.(sprintf('checkboxAux%i',k)).Tag=num2str(k); %add a tag to the checkbox
                    if k>length(dev.auxList)
                        app.(sprintf('popupmenuAux%i',k)).Visible=0; %makes excess aux channels invisible
                        app.(sprintf('checkboxAux%i',k)).Visible=0; %makes excess aux channels invisible
                        app.(sprintf('checkboxAux%i',k)).Value=0; %unchecks checkmark
                        app.auxDisp(1,k)=0;
                    else
                        app.(sprintf('popupmenuAux%i',k)).Visible=1; %make aux channel item box visible
                        app.(sprintf('checkboxAux%i',k)).Visible=1; %makes aux channels checkmark visible
                        app.(sprintf('checkboxAux%i',k)).Value=1; %checks checkmark
                        app.(sprintf('popupmenuAux%i',k)).Items=dev.auxList; %populate the popupmenu
                        app.(sprintf('popupmenuAux%i',k)).Value=dev.auxList{k};
                        app.(sprintf('popupmenuAux%i',k)).FontColor=app.colores(k,:);
                        if strcmp(dev.auxList{k},'NONE')
                            app.(sprintf('checkboxAux%i',k)).Enable=0; %makes non connected channel non selectable
                            app.(sprintf('checkboxAux%i',k)).Value=0; %unchecks display of non connected channel
                            app.(sprintf('popupmenuAux%i',k)).FontColor=[0,0,0];
                        end
                        app.auxDisp(1,k)=1;
                    end
                end
            end


            %% decide how many source controls to display
            %right now it assumes devices have only two wavelengths; a solution
            %will have to be found in the future. It also only supports up
            %to 16 sources and 32 detectors
            for k=1:16
                if k>SD1.nSrcs
                    app.(sprintf('Button_%i',k)).Visible=0;
                    for m=1:SD1.nLambdas
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Visible=0;
                    end
                else
                    %set visibility
                    app.(sprintf('Button_%i',k)).Visible=1;
                    for m=1:SD1.nLambdas
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Visible=1;
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).FontSize=8;
                        %set labels
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Text=lambdas{m};
                        app.(sprintf('togglebuttonSrc%i_%i',k,m)).Tag=[num2str(k),'_',num2str(m)];
                    end

                end
            end

            if isfield(dev,'controllableSources')
                if dev.controllableSources
                    app.uipanelSources.Enable='on';
                else
                    app.uipanelSources.Enable='off';
                end                               
            else
                app.uipanelSources.Enable=1;
            end

            if isfield(dev,'controllableIndividualSources')

            end

            if isfield(dev,'controlSourcesDuringAcquisition')

            end


            %% decide how many detector controls to display

            for k=1:32
                if k>SD1.nDets
                    app.(sprintf('textDet%i',k)).Visible=0;
                else
                    app.(sprintf('textDet%i',k)).Visible=1;
                end
            end


        end

        function [results,limis] = settimeaxis(app)

            %specifies how the x axis should look
            results=1;
            value = app.DisplayWindowsecondsEditField.Value;
            limis=[max(0,app.currt-value),app.currt];
            %             xlim(app.axisPlotData,limis)
        end

        function results = setyaxis(app)

            % specifies how the y axis should look
            results=1;

            if app.togglebuttonDisplayAux.Value
                ylabel(app.axisPlotData,'Amplitude [a.u.]')
            else
                switch app.DisplaytypeButtonGroup.SelectedObject.Text
                    case 'dB'
                        ylabel(app.axisPlotData,'Amplitude [dB]')
                    case 'Delta OD'
                        ylabel(app.axisPlotData,'\DeltaOD')
                    case 'Hemoglobin'
                        ylabel(app.axisPlotData,'\DeltaHb []')
                end
            end

            %             if isempty(app.yl)
            %                 ylim(app.axisPlotData,'auto')
            %             else
            %
            %             end
            if ~app.checkboxAutoscale.Value
                ylim(app.axisPlotData,[app.yminEditField.Value,app.ymaxEditField.Value])
            else
                ylim(app.axisPlotData,'auto')
            end


        end

        function results = save2file(app,fname)
            %routine to save the data to file
            results=0;

            %if no autosave folder is defined, saved in default directory
            if isempty(app.AutoSaveFolderPath)
                if nargin==1
                    if ~exist([[userpath,filesep,'autosave',filesep],char(datetime('now','Format','y-MM-dd'))],'dir')
                        mkdir([[userpath,filesep,'autosave',filesep],char(datetime('now','Format','y-MM-dd'))])
                    end
                    fname=[[userpath,filesep,'autosave',filesep],char(datetime('now','Format','y-MM-dd')),'/',app.deviceInformation.devID,char(datetime('now','Format','y-MM-d-HH-m-ss'))];
                end
            else
                if nargin==1
                    if ~exist([app.AutoSaveFolderPath,filesep,char(datetime('now','Format','y-MM-dd'))],'dir')
                        mkdir([app.AutoSaveFolderPath,filesep,char(datetime('now','Format','y-MM-dd'))])
                    end
                    fname=[[app.AutoSaveFolderPath,filesep],char(datetime('now','Format','y-MM-dd')),'/',app.deviceInformation.devID,char(datetime('now','Format','y-MM-d-HH-m-ss'))];
                end
            end

            data=app.databuffer;
            s=app.stims;
            t=app.time;

            %code to remove NaNs at the end of channels if a channel missed
            %a few samples at the end
            temp1=sum(~isnan(data')); %find the number of not NaN samples on each column. temp1 is a matrix, each column says how many samples in each channel
            temp1=temp1(temp1>4);     %ignore channels if they have less than 4 samples (to avoid having something like one channel with only one sample by error while the other channels have thousands; I chose 4 since it's unlikely four fake samples will make it
            indi=2:min(temp1);   %find the channel with less samples

            %discard exta samples (remove NaNs at the end)
            t=t(indi)';
            d=data(1:end-app.deviceInformation.nAux,indi)';
            aux=data(end-app.deviceInformation.nAux+1:end,indi)';
            s=s(:,indi)';
            s(isnan(s))=0;
            sremote=app.triggersfinal(1,indi)';
            aux=[aux,s]; %concatenate s array

            nSD=app.nSD; %#ok<ADPROPLC>
            if ~isempty(app.SD)
                SD=app.SD;
            else
                SD=nSD; %#ok<ADPROPLC>
                %this is for compatibility with Homer
                SD.MeasList=SD.measList;
                SD.SrcPos=SD.srcPos;
                SD.DetPos=SD.detPos;
                SD.SrcPos3D=SD.srcPos3D;
                SD.DetPos3D=SD.detPos3D;
                SD.Lambda=SD.lambda;
                SD.aux=[];
                SD.SpatialUnit=SD.spatialUnit;
            end

            ml=SD.MeasList;
            dev=app.deviceInformation;


            addpath(genpath('snirf'))
            %                         setpaths

            snirf1=SnirfClass(struct( ...
                'd',d, ...
                's',s, ...
                't',t, ...
                'nSD',nSD, ...
                'SD',SD, ...
                'ml',ml, ...
                'dev',dev, ...
                'aux',aux...
                )); %#ok<ADPROPLC>

            for ki=1:app.deviceInformation.nAux
                snirf1.aux(ki).name=app.deviceInformation.auxList{ki};
            end
            snirf1.aux(app.deviceInformation.nAux+1).name='Keypress stim mark';
            snirf1.metaDataTags = MetaDataTagsClass();
            snirf1.metaDataTags.tags.TimeUnit = 's';


            try
                if ~isdeployed
                    %2023: disabled nirs saving
                    %Save nirs file only when application is not compiled;
                    %that way I can debug more easily
                    %save([fname,'.nirs'],'d','s','t','nSD','SD','ml','dev','aux','sremote','-mat')
                end
                snirf1.Save([fname,'.snirf']);
                results=1;
            catch
                disp('Data could not be saved')
            end
            disp(['Saved to ',fname])

        end

        function SD = updateProbe2DcircularPts(app, SD, EEG_system_file_path)
            if isempty(SD.Landmarks)
                return
            end
            
            % Get 10-5 ref pts positions on unit sphere.
            [sphere.label, sphere.theta, sphere.phi, sphere.r, sphere.xc, sphere.yc, sphere.zc] = textread(EEG_system_file_path,'%s %f %f %f %f %f %f','commentstyle','c++'); %#ok<DTXTRD>
            if isempty(sphere.label)
                return
            end
            
            % ref pt labels used for affine transformation
            refpts_labels = {'T7','T8','Oz','Fpz','Cz','C3','C4','Pz','Fz'};
            
            % get positions for refpts_labels from both sphere and probe ref pts
            for u = 1:length(refpts_labels)
                label = refpts_labels{u};
            
                idx = ismember(SD.Landmarks.labels, label);
                if isempty(idx)
                    return
                end
                probe_refps_pos(u,:) = SD.Landmarks.pos(idx,:);
                idx = ismember(sphere.label, label);
                sphere_refpts_pos(u,:) = [sphere.xc(idx) sphere.yc(idx) sphere.zc(idx)];
            end
            
            % get affine transformation
            % probe_refps*T = sphere_refpts
            probe_refps_pos = [probe_refps_pos ones(size(probe_refps_pos,1),1)];
            T = probe_refps_pos\sphere_refpts_pos;
           
            % get 2D circular refpts for current selecetd reference point system
            probe_refpts_idx =  ismember(sphere.label, SD.Landmarks.labels);
            
            % refpts_2D.pos = [sphere_xc(probe_refpts_idx) sphere_yc(probe_refpts_idx) sphere_zc(probe_refpts_idx)];
            refpts_2D.label = sphere.label(probe_refpts_idx);
            refpts_theta =  sphere.theta(probe_refpts_idx);
            refpts_phi = 90 - sphere.phi(probe_refpts_idx); % elevation angle from top axis
            
            refpts_theta = (2 * pi * refpts_theta) / 360; % convert to radians
            refpts_phi = (2 * pi * refpts_phi) / 360;
            [x,y] = pol2cart(refpts_theta, refpts_phi);      % get plane coordinates
            xy = [x y];
            
            
            norm_factor = max(max(xy));
            xy = xy/norm_factor;          
            refpts_2D.pos = xy;
            SD.Landmarks2D = refpts_2D;
           
            if isfield(SD,'SrcPos3D') & ~isempty(SD.SrcPos3D)
                SD.SrcPos = convert_optodepos_to_circlular_2D_pos(SD.SrcPos3D, T, norm_factor);
            end
            if isfield(SD,'DetPos3D') & ~isempty(SD.DetPos3D)
                SD.DetPos = convert_optodepos_to_circlular_2D_pos(SD.DetPos3D, T, norm_factor);
            end
            if isfield(SD,'DummyPos3D') & ~isempty(SD.DummyPos3D)
                SD.DummyPos = convert_optodepos_to_circlular_2D_pos(SD.DummyPos3D, T, norm_factor);
            end
            
            % -------------------------------------------------------------------------------
            function xy = convert_optodepos_to_circlular_2D_pos(pos, T, norm_factor)
            pos = [pos ones(size(pos,1),1)];
            pos_unit_sphere = pos*T;
            pos_unit_sphere_norm = sqrt(sum(pos_unit_sphere.^2,2));
            pos_unit_sphere = pos_unit_sphere./pos_unit_sphere_norm ;
            
            [azimuth,elevation,r] = cart2sph(pos_unit_sphere(:,1),pos_unit_sphere(:,2),pos_unit_sphere(:,3));
            elevation = pi/2-elevation;
            [x,y] = pol2cart(azimuth,elevation);      % get plane coordinates
            xy = [x y];
            xy = xy/norm_factor;               % set maximum to unit length
            end
        end

        function [results,nSDout,SDout] = validate_nSD(app,nSDfname)
            %%function called when loading a probe file. It makes sure that
            %%it contains the required structure(s) (either/or nSD and SD),
            %%that the structure(s) contain the necessary fields, and
            %%corrects the capitalization if necessary. It returns the
            %%status of the validation (0 if it failed, 1 if it succeeded),
            %%and a structure nSDout which basically is the nSD or SD
            %%structure with corrected capitalization. If the file also
            %%contained an SD structure, it will return it as is
            %%(unmodified, it is used when saving the file)

            results = 0;
            SDout=[];
            nSDout=[];

            errorm='The selected file is not compatible with ninjaGUI';
            %check that the file really exists
            if ~exist(nSDfname,'file')
                disp('The specified file does not exist, try again')
                return
            end

            %check the variables stored in the file
            varias=who('-file',nSDfname);

            %the following booleans will determine if the file contains
            %either the SD or the nSD structures
            nSDpresent=any(ismember(varias,'nSD'));
            SDpresent=any(ismember(varias,'SD'));

            %return if neither exist
            if ~(nSDpresent||SDpresent)
                disp(errorm)
                return
            end

            %return a copy of the original SD file
            if SDpresent
                load(nSDfname,'SD','-mat');
                if isfield(SD,'SrcPos') && isfield(SD,'DetPos')
                    if all(SD.SrcPos(:))==0 && all(SD.DetPos(:)==0)
                        apppath = mfilename('fullpath');
                        [appfolder,name,ext] = fileparts(apppath);
                        EEG_system_file_path = [appfolder filesep '10-5-System_Mastoids_EGI129.csd'];
                        if isfile(EEG_system_file_path)
                            SD = updateProbe2DcircularPts(app, SD, EEG_system_file_path);
                        end
                    end
                end
                SDout=SD; %#ok<ADPROPLC>
            end

            %now, if nSD exist, validate it has the required fields, if it
            %does, return the nSD structure as is. If it doesn't, create a
            %copy of SD and rename the fields
            if nSDpresent
                load(nSDfname,'nSD','-mat');
                try
                    %if one of the fields is missing, an error will occur
                    nSD.measList; %#ok<ADPROPLC>
                    nSD.nLambdas; %#ok<ADPROPLC>
                    nSD.freqMap; %#ok<ADPROPLC>
                    nSD.srcPos; %#ok<ADPROPLC>
                    nSD.detPos; %#ok<ADPROPLC>
                    nSD.nSrcs; %#ok<ADPROPLC>
                    nSD.nDets; %#ok<ADPROPLC>
                catch
                    disp('The specified file is not formatted correctly')
                    results = 0;
                    return
                end
                nSDout=nSD; %#ok<ADPROPLC>
                results=1;
            else
                if strcmp(app.deviceInformation.devID,'ninjaNIRS2020')||strcmp(app.deviceInformation.devID,'ninjaNIRS')
                    disp('The specified probe file is not compatible with this device')
                    return
                else
                    try
                        %if one of the fields is missing, an error will occur
                        SD.MeasList; %#ok<ADPROPLC>
                        SD.SrcPos; %#ok<ADPROPLC>
                        SD.DetPos; %#ok<ADPROPLC>
                        SD.nSrcs; %#ok<ADPROPLC>
                        SD.nDets; %#ok<ADPROPLC>
                        SD.Lambda; %#ok<ADPROPLC>
                    catch
                        disp('The specified file is not formatted correctly')
                        results = 0;
                        return
                    end
                    nSDout=SD; %#ok<ADPROPLC>
                    %add fields with appropriate name
                    nSDout.device=app.deviceInformation.devID; %extract device name from cfg file
                    nSDout.lambda=nSDout.Lambda;
                    nSDout.spatialUnit=nSDout.SpatialUnit;
                    nSDout.measList=nSDout.MeasList;
                    nSDout.nLambdas=length(nSDout.Lambda);
                    nSDout.srcPos=nSDout.SrcPos;
                    nSDout.detPos=nSDout.DetPos;
                    nSDout.nStates=max(nSDout.measList(:,3));
                    %nSDout.freqMap=ones(nSDout.nSrcs,nSDout.nStates,nSDout.nLambdas);
                    results=1;
                end
            end
        end

        function result = DrawNumbers(app, hn, dn, wn)
            %% This funtion attempts to write source and detector labels onto the
            %% UI without overlapping any existing marks on the chart

            SrcPos=app.nSD.srcPos;
            DetPos=app.nSD.detPos;
            nSrcs=app.nSD.nSrcs;
            nDets=app.nSD.nDets;
            ax=app.axesSDG;
            fs=20;  %font size for sd numbers

            %Decide the visibility of each source and detector number; the
            %idea is to make the inactive ones invisible
            ML=app.nSD.measList;
            flags=cell(1,2);
            flags{1,1}=ones(1,nSrcs);
            flags{1,2}=ones(1,nDets);
            flags{1,1}(setdiff(1:nSrcs,ML(:,1)'))=0;
            flags{1,2}(setdiff(1:nDets,ML(:,2)'))=0;

            %Sources
            for k=1:nSrcs
                %Decide if number will actually be drawn
                if ~flags{1,1}(k)
                    continue
                end

                % account for wider numbers
                wn = (floor(log10(k))+1)*wn;

                %Get possible interferences - horizontal
                Space_Left =    SrcPos(:,1)>=(SrcPos(k,1)-dn-wn)  &  SrcPos(:,1)<=SrcPos(k,1);
                Space_Left(k) = 0;
                Space_Left =  [(DetPos(:,1)>=(SrcPos(k,1)-dn-wn)  &  DetPos(:,1)<=SrcPos(k,1)); Space_Left];

                Space_Right =   SrcPos(:,1)<=(SrcPos(k,1)+dn+wn)  &  SrcPos(:,1)>=SrcPos(k,1);
                Space_Right(k) = 0;
                Space_Right = [(DetPos(:,1)<=(SrcPos(k,1)+dn+wn)  &  DetPos(:,1)>=SrcPos(k,1)); Space_Right];

                Near_Up =      SrcPos(:,2)<=(SrcPos(k,2)+hn)  &  SrcPos(:,2)>=SrcPos(k,2);
                Near_Up(k) = 0;
                Near_Up =    [(DetPos(:,2)<=(SrcPos(k,2)+hn)  &  DetPos(:,2)>=SrcPos(k,2)); Near_Up];

                Near_Down =    SrcPos(:,2)>=(SrcPos(k,2)-hn)  &  SrcPos(:,2)<=SrcPos(k,2);
                Near_Down(k) = 0;
                Near_Down =  [(DetPos(:,2)>=(SrcPos(k,2)-hn)  &  DetPos(:,2)<=SrcPos(k,2)); Near_Down];

                %Get possible interferences - vertical
                Space_Up =      SrcPos(:,2)<=(SrcPos(k,2)+2*hn)  &  SrcPos(:,2)>=SrcPos(k,2);
                Space_Up(k) = 0;
                Space_Up =    [(DetPos(:,2)<=(SrcPos(k,2)+2*hn)  &  DetPos(:,2)>=SrcPos(k,2)); Space_Up];

                Space_Down =    SrcPos(:,2)>=(SrcPos(k,2)-2*hn)  &  SrcPos(:,2)<=SrcPos(k,2);
                Space_Down(k) = 0;
                Space_Down =  [(DetPos(:,2)>=(SrcPos(k,2)-2*hn)  &  DetPos(:,2)<=SrcPos(k,2)); Space_Down];

                Near_Left =    SrcPos(:,1)>=(SrcPos(k,1)-wn)  &  SrcPos(:,1)<=SrcPos(k,1);
                Near_Left(k) = 0;
                Near_Left =  [(DetPos(:,1)>=(SrcPos(k,1)-wn)  &  DetPos(:,1)<=SrcPos(k,1)); Near_Left];

                Near_Right =   SrcPos(:,1)<=(SrcPos(k,1)+wn)  &  SrcPos(:,1)>=SrcPos(k,1);
                Near_Right(k) = 0;
                Near_Right = [(DetPos(:,1)<=(SrcPos(k,1)+wn)  &  DetPos(:,1)>=SrcPos(k,1)); Near_Right];

                %Get just the areas adjacent our point
                LeftSide  = ((Space_Left & Near_Down)|(Space_Left & Near_Up))...
                    & ~[DetPos(:,1)==SrcPos(k,1); SrcPos(:,1)==SrcPos(k,1)];

                RightSide = ((Space_Right & Near_Down)|(Space_Right & Near_Up))...
                    & ~[DetPos(:,1)==SrcPos(k,1); SrcPos(:,1)==SrcPos(k,1)];

                DownSide  = ((Near_Left & Space_Down)|(Near_Right & Space_Down))...
                    & ~[DetPos(:,2)==SrcPos(k,2); SrcPos(:,2)==SrcPos(k,2)];

                UpSide    = ((Near_Left & Space_Up)|(Near_Right & Space_Up))...
                    & ~[DetPos(:,2)==SrcPos(k,2); SrcPos(:,2)==SrcPos(k,2)];

                %Position labels according to which matrix is empty
                %(default left)
                if(~any(LeftSide(:)))
                    offsetx=-dn-wn;
                    offsety=0;
                elseif(~any(RightSide(:)))
                    offsetx=dn;
                    offsety=0;
                elseif(~any(UpSide(:)))
                    offsetx=-wn/2;
                    offsety=hn;
                elseif(~any(DownSide(:)))
                    offsetx=-wn/2;
                    offsety=-hn;
                else
                    offsetx=-dn-wn;
                    offsety=0;
                end

                h = text(ax,SrcPos(k,1)+offsetx,...
                    SrcPos(k,2)+offsety,num2str(k),...
                    'Color',[1,0,0],'FontSize',fs,...
                    'Tag',['Src',num2str(k)],...
                    'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
                set(h,'Clipping', 'on');

                % Avoid overcompensating for wider numbers
                wn = wn/(floor(log10(k))+1);
            end

            %Detectors
            for k=1:nDets
                if ~flags{1,2}(k)
                    continue
                end
                % account for wider numbers
                wn = (floor(log10(k))+1)*wn;

                %Get possible interferences - horizontal
                Space_Left =    DetPos(:,1)>=(DetPos(k,1)-dn-wn)  &  DetPos(:,1)<DetPos(k,1);
                Space_Left(k) = 0;
                Space_Left =  [(SrcPos(:,1)>=(DetPos(k,1)-dn-wn)  &  SrcPos(:,1)<DetPos(k,1)); Space_Left];

                Space_Right =   DetPos(:,1)<=(DetPos(k,1)+dn+wn)  &  DetPos(:,1)>=DetPos(k,1);
                Space_Right(k) = 0;
                Space_Right = [(SrcPos(:,1)<=(DetPos(k,1)+dn+wn)  &  SrcPos(:,1)>=DetPos(k,1)); Space_Right];

                Near_Up =      DetPos(:,2)<=(DetPos(k,2)+hn)  &  DetPos(:,2)>DetPos(k,2);
                Near_Up(k) = 0;
                Near_Up =    [(SrcPos(:,2)<=(DetPos(k,2)+hn)  &  SrcPos(:,2)>DetPos(k,2)); Near_Up];

                Near_Down =    DetPos(:,2)>=(DetPos(k,2)-hn)  &  DetPos(:,2)<=DetPos(k,2);
                Near_Down(k) = 0;
                Near_Down =  [(SrcPos(:,2)>=(DetPos(k,2)-hn)  &  SrcPos(:,2)<=DetPos(k,2)); Near_Down];

                %Get possible interferences - vertical
                Space_Up =      DetPos(:,2)<=(DetPos(k,2)+2*hn)  &  DetPos(:,2)>DetPos(k,2);
                Space_Up(k) = 0;
                Space_Up =    [(SrcPos(:,2)<=(DetPos(k,2)+2*hn)  &  SrcPos(:,2)>DetPos(k,2)); Space_Up];

                Space_Down =    DetPos(:,2)>=(DetPos(k,2)-2*hn)  &  DetPos(:,2)<=DetPos(k,2);
                Space_Down(k) = 0;
                Space_Down =  [(SrcPos(:,2)>=(DetPos(k,2)-2*hn)  &  SrcPos(:,2)<=DetPos(k,2)); Space_Down];

                Near_Left =    DetPos(:,1)>=(DetPos(k,1)-wn)  &  DetPos(:,1)<DetPos(k,1);
                Near_Left(k) = 0;
                Near_Left =  [(SrcPos(:,1)>=(DetPos(k,1)-wn)  &  SrcPos(:,1)<DetPos(k,1)); Near_Left];

                Near_Right =   DetPos(:,1)<=(DetPos(k,1)+wn)  &  DetPos(:,1)>=DetPos(k,1);
                Near_Right(k) = 0;
                Near_Right = [(SrcPos(:,1)<=(DetPos(k,1)+wn)  &  SrcPos(:,1)>=DetPos(k,1)); Near_Right];


                %Get just the areas adjacent our point
                LeftSide  = ((Space_Left & Near_Down)|(Space_Left & Near_Up))...
                    & ~[SrcPos(:,1)==DetPos(k,1); DetPos(:,1)==DetPos(k,1)];

                RightSide = ((Space_Right & Near_Down)|(Space_Right & Near_Up))...
                    & ~[SrcPos(:,1)==DetPos(k,1); DetPos(:,1)==DetPos(k,1)];

                DownSide  = ((Near_Left & Space_Down)|(Near_Right & Space_Down))...
                    & ~[SrcPos(:,2)==DetPos(k,2); DetPos(:,2)==DetPos(k,2)];

                UpSide    = ((Near_Left & Space_Up)|(Near_Right & Space_Up))...
                    & ~[SrcPos(:,2)==DetPos(k,2); DetPos(:,2)==DetPos(k,2)];


                %Position labels according to which matrix is empty
                %(default right)
                if(~any(LeftSide(:)))
                    offsetx=-dn-wn;
                    offsety=0;
                elseif(~any(RightSide(:)))
                    offsetx=dn;
                    offsety=0;
                elseif(~any(UpSide(:)))
                    offsetx=-wn/2;
                    offsety=hn;
                elseif(~any(DownSide(:)))
                    offsetx=-wn/2;
                    offsety=-hn;
                else
                    offsetx=dn;
                    offsety=0;
                end

                h = text(ax,DetPos(k,1)+offsetx,...
                    DetPos(k,2)+offsety,num2str(k),...
                    'Color',[0,0,1],'FontSize',fs,...
                    'Tag',['Det',num2str(k)],...
                    'ButtonDownFcn',createCallbackFcn(app, @SDGcallback, true));
                set(h,'Clipping', 'on');

                % Avoid overcompensating for wider numbers
                wn = wn/(floor(log10(k))+1);
            end

            result = 1;
        end

    end

    methods (Access = public)
        function results = SDGcallback(app,event)
            %%function called when a source, detector or channel are clicked on
            %%the SD plot; it determines how to update the selection of the
            %%graphic objects and sets a state variable currchans indicating
            %%which channel from the measurement list we want to plot. If
            %%on ID mode, it will turn on the LED of that source to high
            %%level instead
            
%             app.event = event;
            results=1;
            evtag=event.Source.Tag; %tag of the clicked element
            ML=app.nSD.measList;

            objtype=evtag(1:3);
            objnum=str2double(evtag(4:end));


            %Channel select mode
            nChans=size(ML,1)/app.nSD.nLambdas;

            if strcmp(objtype,'Ind')
                objtype='Cha';
                evtag=[objtype,num2str(objnum)];
            end

            %decide how to proceed depending on if we selected a channel, a
            %source or a detector. In case of selecting a channel, all
            %current channels but the clicked one will be deselected; if
            %the clicked one was selected already, then no channel will be
            %selected. In the case of pressing a detector, the program will
            %find what channels are measured by that source, and select all
            %of them; in the case of a source, we will find out what
            %channels are fed by that source, and select them; again, if it
            %was selected already, we will deselect everything.
            switch objtype
                case {'Cha','Ind'}
                    selmeas=objnum;

                    %set all channel colors to grey
                    for k=1:nChans
                        app.axesSDG.Children(end-2*nChans+k).Color=[0.7,0.7,0.7]; %assumes channels were drawn first
                    end

                    %check if the selected channel is equal to the
                    %currently active channel

                    if isequal(selmeas,app.currchans)
                        %since the selection and the current selection is
                        %the same, deselect selected channel. However, this
                        %is only allowed if the app is stopped. If it's
                        %running, it will keep it selected
                        if ~app.active
                            app.currchans=[];
                        else
                            %set the selected channel color to cyan
                            h=findobj(app.axesSDG.Children,'Tag',evtag);
                            h.Color=app.colores(1,:);

                            %save which channel was selected
                            app.currchans=selmeas;
                        end
                    else
                        %set the selected channel color to cyan
                        h=findobj(app.axesSDG.Children,'Tag',evtag);
                        h.Color=app.colores(1,:);

                        %save which channel was selected
                        app.currchans=selmeas;
                    end
                case 'Det'
                    selmeas=find(ML(1:nChans,2)==objnum); %find which measurements are connected to clicked detector
                    if isempty(selmeas)
                        return
                    end

                    %set all channel colors to grey
                    for k=1:nChans
                        app.axesSDG.Children(end-2*nChans+k).Color=[0.7,0.7,0.7]; %assumes channels were drawn first
                    end

                    %check if clicking on same detector as before

                    if isequal(selmeas,app.currchans)
                        %if so, this means we need to deselect all
                        %channels, but only if the app is inactive
                        if ~app.active
                            app.currchans=[];
                        else
                            for k=1:length(selmeas)
                                h=findobj(app.axesSDG.Children,'Tag',['Cha',num2str(selmeas(k))]);
                                h.Color=app.colores(k,:);
                            end
                            app.currchans=selmeas;
                        end

                    else
                        %if not equal, set selected channel to selmeas, and
                        %change color of selected channels
                        for k=1:length(selmeas)
                            h=findobj(app.axesSDG.Children,'Tag',['Cha',num2str(selmeas(k))]);
                            h.Color=app.colores(k,:);
                        end
                        app.currchans=selmeas;
                    end

                case 'Src'
                    selmeas=find(ML(1:nChans,1)==objnum); %find which measurements are connected to clicked source
                    if isempty(selmeas)
                        return
                    end
                    %set all channel colors to grey
                    for k=1:nChans
                        app.axesSDG.Children(end-2*nChans+k).Color=[0.7,0.7,0.7];
                    end

                    %check if clicking on same detector as before
                    if isequal(selmeas,app.currchans)
                        %if so, this means we need to deselect all
                        %channels, but only if the app is inactive
                        if ~app.active
                            app.currchans=[];
                        else
                            for k=1:length(selmeas)
                                h=findobj(app.axesSDG.Children,'Tag',['Cha',num2str(selmeas(k))]);
                                h.Color=app.colores(k,:);
                            end
                            app.currchans=selmeas;
                        end

                    else
                        %if not equal, set selected channel to selmeas, and
                        %change color of selected channels
                        for k=1:length(selmeas)
                            h=findobj(app.axesSDG.Children,'Tag',['Cha',num2str(selmeas(k))]);
                            h.Color=app.colores(k,:);
                        end
                        app.currchans=selmeas;
                    end

            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DeviceInfo, Environment)
            %Startup routine

            if isdeployed
                %prevents debug mode from ever happening in the compiled
                %version
                app.debugMode=0;
            end

            % define colors
            app.colores=[...
                0    0.4470    0.7410;...
                0.8500    0.3250    0.0980;...
                0.9290    0.6940    0.1250;...
                0.4940    0.1840    0.5560;...
                0.4660    0.6740    0.1880;...
                0.3010    0.7450    0.9330;...
                0.6350    0.0780    0.1840
                [1,0,0];...
                [0,1,0];...
                [0,0,1];...
                [0,1,1];...
                [1,0,1];...
                [1,1,0];...
                ];
            %due to this length, the app will crash if more than 12
            %channels are selected to be displayed at a time, so this needs
            %to be updated at some point

            %default settings for the color coding of saturation; only
            %enabled if the cfg file specifies the thresholds
            if isfield(DeviceInfo,'levelRepresentation')
                app.levelRepresentation.thresholds=DeviceInfo.levelRepresentation.thresholds;
                app.levelRepresentation.color.saturated=[1,0.6,.6,.8];
                app.levelRepresentation.color.weak=[.6,0.6,1,0.8];
                app.levelRepresentation.color.notAnumber=[0.9,0.9,0.9];
                if app.debugMode
                    app.SDGdisplaysignallevelMenu.Checked=false;
                end
            else
                app.SDGdisplaysignallevelMenu.Enable=false;
                app.SDGdisplaysignallevelMenu.Checked=false;
            end

            app.lineS=[':','--','-.','-.'];

            app.ninjaGUI20000betaUIFigure.Visible='off';
            movegui(app.ninjaGUI20000betaUIFigure,'center')

            %Disable debug button
            if ~app.debugMode
                app.DebugButton.Visible=0;
            end

            %%load configuration from input
            app.deviceInformation=Environment.info;
            app.deviceFunctions=Environment.functions;
            app.communicationParameters=Environment.commSpecs;

            %% create communication object
            
            disp(['Connecting to serial port ',app.deviceInformation.commPort])
            [~,errors]=app.deviceFunctions.CommunicationPort(app,'open');
            if errors
                error(['Serial communication NOT established! Make sure USB is ' ...
                'properly connected, serial port is correct and drivers are installed'])
            end

            %% call sd file callback
            menuLoadSDGMenuSelected(app);

            %% ask status
            try
                app.deviceFunctions.Ask4Status(app);
                success=1;
            catch
                disp('Instrument status could not be identified. Is the device connected or powered?')
                success=0;
            end

            %if everything went well, make main window visible
            if success
                app.ninjaGUI20000betaUIFigure.Visible='on';
                movegui(app.ninjaGUI20000betaUIFigure,"center");
            else
                %A=menu('Something went wrong when trying to initialize device. Try again? ','Yes','No');
                disp('Something went wrong when initializing the device. Check all connections and try starting the program again')
                fNIRS_exit(app);
            end


        end

        % Button pushed function: pushbuttonStimMark
        function pushbuttonStimMarkButtonPushed(app, event)
            app.stims(floor(app.currt*app.editRate.Value))=1;
        end

        % Value changed function: togglebuttonStartStop
        function acquisitionFunction(app, event)
            %             try
            % Routine for starting the acquisition

            if app.active
                %if it's active already, deactivate!

                %change button label
                app.togglebuttonStartStop.Text='START';

                %flag to indicate that the app is not running anymore
                app.active=0;

                if app.deviceInformation.AdjustableRate
                    app.editRate.Enable=1;
                end
            else
                %then start running, if it's not active

                %stop if no channel is selected
                if isempty(app.currchans)
                    app.togglebuttonStartStop.Value=0;
                    uialert(app.ninjaGUI20000betaUIFigure,'Select a channel first','Invalid channel selection');
                    return
                end

                %% Initialize axes
                % clear figure to remove graphics trash
                cla(app.axisPlotData);
                %set labels for axes
                xlabel(app.axisPlotData,'Time [s]')
                setyaxis(app);
                hold(app.axisPlotData,'on')

                %% Make active
                %change button label
                app.togglebuttonStartStop.Text='STOP';
                %flag that the app is running now
                app.active=1;

                %% Memory and serial buffer initializations
                % clear databuffer to save memory
                app.databuffer=[];
                %flush buffer
                app.deviceFunctions.CommunicationPort(app,'flush');

                %%Autosave
                %create identifier for file
                foldname=char(datetime('now','Format','y-MM-dd'));
                fname=char(datetime('now','Format','y-MM-dd-HH-mm-ss'));
                %create autosave directory
%                 if ~exist(['autosave',filesep,foldname],'dir')
%                     mkdir(['autosave',filesep,foldname])
%                 end
                % Below if updated by SK to create saving folder with
                % datestamp in probe dircetory instead of autoSave
                % directory
                if ~exist([app.AutoSaveFolderPath,foldname],'dir')
                    mkdir([app.AutoSaveFolderPath,foldname])
                end
                %the following is used to initalize saving the bytestream
                %to file in case there is a crash
                if app.debugMode
%                     app.fstreamID=fopen(['autosave',filesep,foldname,filesep,app.deviceInformation.devID,'_',fname,'.bin'],'w+');
                    % Below line is updated by SK to open fID in probe
                    % directory instead of autoSave directory 
                    app.fstreamID=fopen([app.AutoSaveFolderPath,foldname,filesep,app.deviceInformation.devID,'_',fname,'.bin'],'w+');
                end

                fname=[app.deviceInformation.devID,'_',fname];

                fname_sidecar = [fname,'_stateMap.mat'];
                if isfield(app.deviceInformation,'stateMap')
                    stateMap = app.deviceInformation.stateMap;
                else % save NN24 srcram as stateMap
                    stateMap = app.deviceInformation.srcram;
                end
                stateIndices = app.deviceInformation.stateIndices;
                devInfo.flagSpatialMultiplex = app.deviceInformation.flagSpatialMultiplex;
                devInfo.state_fs = app.deviceInformation.stat.state_fs;
                devInfo.acc_active = app.deviceInformation.stat.acc_active;
                devInfo.aux_active = app.deviceInformation.stat.aux_active;
                devInfo.N_DETECTOR_BOARDS = app.deviceInformation.stat.n_detb_active;
                devInfo.stat.n_smp = app.deviceInformation.stat.n_smp;
                if app.deviceInformation.stat.acc_active
                    devInfo.stat.accfs = app.deviceInformation.stat.accfs;
                    devInfo.stat.gyrofs = app.deviceInformation.stat.gyrofs;
                end
                nSD = app.nSD;
                if ~isfield(app.deviceInformation,'optPowerLevel')
                    save([app.AutoSaveFolderPath,foldname,filesep,fname_sidecar],'nSD','stateMap','stateIndices','devInfo');
                else
                    devInfo.dataLEDPowerCalibration = app.deviceInformation.dataLEDPowerCalibration;
                    devInfo.optPowerLevel = app.deviceInformation.optPowerLevel;
                    devInfo.srcPowerLowHigh = app.deviceInformation.srcPowerLowHigh;
                    devInfo.dSig = app.deviceInformation.dSig;
                    devInfo.srcModuleGroups = app.deviceInformation.srcModuleGroups;
                    save([app.AutoSaveFolderPath,foldname,filesep,fname_sidecar],'nSD','stateMap','stateIndices','devInfo');
                end
                
                %start aqcquisition
                app.deviceFunctions.Acquisition(app,'start');

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %% Code for the conversion to Hb based on the hardware wavelengths
                e=GetExtinctions(app.deviceInformation.Wavelengths)/10;
                e=e(:,1:2);
                einv = (e'*e )\e';
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % acquisition properties
                app.editRate.Enable=0;
                N_SAMPS_ACQ=0;
                N_SAMPLES_TO_ACQ=ceil(60*app.nMins*app.editRate.Value);

                data=nan(size(app.nSD.measList,1)+app.deviceInformation.nAux,N_SAMPLES_TO_ACQ);
                %                 datastats=nan(size(app.nSD.measList,1)+app.deviceInformation.nAux,N_SAMPLES_TO_ACQ,2);
                %datab=nan(8,N_SAMPLES_TO_ACQ,6);
                t=(0:N_SAMPLES_TO_ACQ-1)./app.editRate.Value;
                app.stims=0*t;
                app.triggersfinal=0*t;
                Nchans=size(app.nSD.measList,1)/app.nSD.nLambdas; %number of channels

                %clocks
                tic;    %initialize a clock
                tp1=0;  %last plotting time variable
                tt1=0;  %timeout timer variable

                while app.togglebuttonStartStop.Value && max(N_SAMPS_ACQ)<N_SAMPLES_TO_ACQ %do while button is on the position and we have not read enough samples

                    if toc-tt1>10
                        disp('Instrument timeout: instrument has stopped sending data')
                        break
                    end

                    %%acquire data

                    % I added the following if/else to keep compatibility
                    % with the current implementation of
                    % funcSet.ReadBytesAvailable for nirs1k. If the field
                    % is not in the config file, a slightly different
                    % function call is used to prevent crashing if the
                    % readbytesavailable function is not compatible with
                    % returning avgv. Another similar code will be used
                    % below; ideally the readbytesavailable function for
                    % all instruments should be able to handle the function
                    % call
                    if isfield(app.deviceInformation,'optodeSaturationThreshold')
                        if app.deviceInformation.optodeSaturationThreshold
                            [datatemp,packlen,app.rbytes,~,~,~,avgv]=app.deviceFunctions.ReadBytesAvailable(app);
                        else
                            [datatemp,packlen,app.rbytes]=app.deviceFunctions.ReadBytesAvailable(app);                        
                        end                        
                        
                    else
                        [datatemp,packlen,app.rbytes]=app.deviceFunctions.ReadBytesAvailable(app);                        
                    end

                    if max(packlen)==0
                        continue
                    end
                    %determine data package size and store in data
                    %buffer
                    N_SAMPS_ACQ=N_SAMPS_ACQ+packlen;  %number of currently acquired samples; if unequal per channel, will be a column vector
                    if length(N_SAMPS_ACQ)>1
                        %                         if app.debugMode
                        %                             N_SAMPS_ACQ1=N_SAMPS_ACQ;
                        %                         else
                        %                             N_SAMPS_ACQ1=N_SAMPS_ACQ(1:Nchans);
                        %                         end
                        N_SAMPS_ACQ1=N_SAMPS_ACQ(1:Nchans);
                        app.currt=max(N_SAMPS_ACQ1(N_SAMPS_ACQ1>0))/app.editRate.Value; %current time equals the channel with the least samples
                    else
                        app.currt=max(N_SAMPS_ACQ(N_SAMPS_ACQ>0))/app.editRate.Value; %current time equals the channel with the least samples
                    end
                    %app.currt=min(N_SAMPS_ACQ(N_SAMPS_ACQ>0))/app.editRate.Value; %current time equals the channel with the least samples
                    if isempty(app.currt)
                        app.currt=0;
                    end

                    if isscalar(packlen)
                        data(:,N_SAMPS_ACQ-packlen+1:N_SAMPS_ACQ)=datatemp;
                        %                         if ~isempty(triggstemp)
                        %                             app.triggersfinal(1,N_SAMPS_ACQ-packlen+1:N_SAMPS_ACQ)=triggstemp;
                        %                         end
                    else
                        %unfortunately, this is what needs to be done
                        %for unequal packet sizes, and it will be less
                        %efficient than what is done for equal packet
                        %sizes
                        for ipl=1:size(packlen,1)
                            data(ipl,N_SAMPS_ACQ(ipl)-packlen(ipl)+1:N_SAMPS_ACQ(ipl))=datatemp(ipl,1:packlen(ipl));
                        end
                    end

                    if app.streaming&&app.DatastreamingMenu.Checked  %stream if streaming is enabled
                        % stream data temp
                        app.LSLstreamer.outlet.push_chunk(...
                            [...
                            data(:,min(N_SAMPS_ACQ-packlen)+1:min(N_SAMPS_ACQ));...
                            app.stims(1,min(N_SAMPS_ACQ-packlen)+1:min(N_SAMPS_ACQ));...
                            %app.triggersfinal(1,min(N_SAMPS_ACQ-packlen)+1:min(N_SAMPS_ACQ))...
                            ]...
                            );
                    end

                    %plot but only every .1 second to prevent
                    %plotting bottlenecks; as the app designer plot
                    %function is very slow

                    if toc>tp1+0.1
                        %all or at least most of this code should be sent
                        %to a drawing axes function

                        %determine what wavelengths we want to visualize
                        [~,lambdai]=ismember(app.WavelengthdisplayListBox.Value,app.WavelengthdisplayListBox.Items);

                        [~,limis]=settimeaxis(app);
                        limis1=floor(limis(1)*app.editRate.Value);
                        limis2=floor(limis(2)*app.editRate.Value);
                        if limis1==0
                            limis1=1;
                        end
                        if limis2>app.nMins
                            limis2=N_SAMPLES_TO_ACQ;
                        end

                        % indices of measurement list to display
                        MLi=app.currchans+(lambdai-1)*Nchans;
                        %subset of time vector
                        t1=t(1,limis1:limis2);

                        if ~app.togglebuttonDisplayAux.Value %if display aux button not pressed
                            %%plot fNIRS data
                            %create plot buffer, code will change a little
                            %if filtering is on to prevent initial spike
                            %visualization
                            if strcmp(app.LowpassvisualizationMenu.Checked,'on')
                                %the following if is used to prevent the
                                %spikes at the beginning of the IIR filter
                                %on the visualization. If not enough
                                %samples are there, it will do a moving
                                %average filter instead
                                if limis1-app.butterFilter.order*16<1
                                    plotbuffer=data(MLi(:),limis1:limis2);
                                    plotbuffer=movmean(plotbuffer,7,2);
                                else
                                    addtlsamps=max(1,limis1-app.butterFilter.order*16);
                                    plotbuffer=data(MLi(:),addtlsamps:limis2);
                                    plotbuffer=sosfilt(app.butterFilter.sos,plotbuffer,2);
                                    plotbuffer(:,1:limis1-addtlsamps)=[]; %remove the first few samples to "shift" the buffer
                                    plotbuffer=plotbuffer-min(plotbuffer(:)); %to prevent warnings
                                end
                            else
                                plotbuffer=data(MLi(:),limis1:limis2);
                            end
                            %% calculate what will be plotted based on display type
                            switch app.DisplaytypeButtonGroup.SelectedObject.Text
                                case 'Intensity'

                                case 'dB'
                                    plotbuffer=20*log10(plotbuffer);
                                case 'Delta OD'
                                    plotbuffer=log10(plotbuffer);
                                    plotbuffer=-(plotbuffer-nanmean(plotbuffer,2));
                                case 'Hemoglobin'
                                    plotbuffer=log10(plotbuffer);
                                    plotbuffer=-(plotbuffer-nanmean(plotbuffer,2));
                                    plotbuffer=kron(einv,eye(length(app.currchans)))*plotbuffer; %this will break if I only plot one wavelength
                            end
                            if app.displayCheckBox.Value
                                for li=1:length(lambdai)
                                    plotb=plotbuffer(1+(li-1)*length(app.currchans):li*length(app.currchans),:);
                                    h=plot(app.axisPlotData,t1,plotb,'lineStyle',app.lineS(lambdai(li)));
                                    set(h,{'color'},num2cell(app.colores(1:length(app.currchans),:),2))
                                    hold(app.axisPlotData,'on')
                                end
                                setyaxis(app);
                                yl=ylim(app.axisPlotData);
                            end

                        else %if display aux button is pressed, it plots aux channels instead of fNIRS
                            if app.displayCheckBox.Value
                                %create plot buffer
                                plotbuffer=data(end-app.deviceInformation.nAux+1:end,limis1:limis2);
                                h1=plot(app.axisPlotData,t1,plotbuffer);
                                for iaux=1:app.deviceInformation.nAux
                                    h1(iaux).Visible=app.(sprintf('checkboxAux%i',iaux)).Value;
                                    h1(iaux).Color=app.colores(iaux,:);
                                end
                                hold(app.axisPlotData,'on')
                                setyaxis(app);
                                yl=ylim(app.axisPlotData);
                            end
                        end
                        if app.displayCheckBox.Value
                            %set time axis
                            if diff(limis)>0
                                xlim(app.axisPlotData,limis)
                            end
                            %plot stim
                            try
                                plot(app.axisPlotData,t1,app.stims(:,limis1:limis2)*yl(2),'m');
                            catch ME
                                disp(ME.message )
                            end
                            %%plot remote trigger
                            %plot(app.axisPlotData,t1,app.triggersfinal(1,limis1:limis2)*yl(2),'k');

                            ylim(app.axisPlotData,yl);
                            hold(app.axisPlotData,'off')
                        end

                        if isfield(app.deviceInformation,'optodeSaturationThreshold')
                            for ki=1:size(avgv,1)
                                if avgv(ki,1)>app.deviceInformation.optodeSaturationThreshold & ki<=32
                                    app.(sprintf('textDet%i',ki)).FontColor=[1,0,0];
                                elseif ki<=32
                                    app.(sprintf('textDet%i',ki)).FontColor=[0,0,0];
                                end
                            end
                        end

                        drawnow limitrate %this commands skips frames if there's a bottleneck

                        %update time of last plot
                        tp1=toc;

                        %display intensity representation
                        if app.SDGdisplaysignallevelMenu.Checked
                            numChans=size(app.nSD.measList,1)/2;
                            chanIntens=20*log10(datatemp(numChans+1:2*numChans,1));
                            nivel=zeros(1,length(chanIntens));
                            nivel(chanIntens>app.levelRepresentation.thresholds(2))=1;
                            nivel(chanIntens<app.levelRepresentation.thresholds(1))=-1;
                            nivel(isnan(chanIntens))=nan;
                            for cii=1:(size(app.nSD.measList,1)/2)
                                if nivel(cii)==1
                                    app.axesSDG.Children(end-cii+1).Color=app.levelRepresentation.color.saturated; %assumes channels were drawn first
                                elseif nivel(cii)==-1
                                    app.axesSDG.Children(end-cii+1).Color=app.levelRepresentation.color.weak; %assumes channels were drawn first
                                else
                                    app.axesSDG.Children(end-cii+1).Color=app.levelRepresentation.color.notAnumber; %assumes channels were drawn first
                                end
                            end
                        end
                    end
                    tt1=toc;


                end

                if app.deviceInformation.AdjustableRate
                    app.editRate.Enable=1;
                end

                % stop acquisition
                app.deviceFunctions.Acquisition(app,'stop');

                % save generated data as state variable
                app.databuffer=data;
                app.time=t;

                % autosave to file if activated
                if app.menu_AutoSave.Checked
                    %save
                    app.togglebuttonStartStop.Enable=0;
                    app.togglebuttonStartStop.Text='SAVING...';
                    pause(.1)
                    
                    % No longer needed this autosave folder
%                     if ~exist(['autosave/',char(datetime('now','Format','y-MM-dd'))],'dir')
%                         mkdir(['autosave/',char(datetime('now','Format','y-MM-dd'))])
%                     end

                    %tic
%                     save2file(app,['autosave/',char(datetime('now','Format','y-MM-dd')),'/',fname]);
                    % Below line updated by SK to save snirf file in probe
                    % directory instead of autoSave directory
                    % updated on 05052023 to stop saving snirf by defalut
%                     save2file(app,[app.AutoSaveFolderPath,char(datetime('now','Format','y-MM-dd')),'/',fname]);
                    %toc
                end


                %close temporary byte stream
                if app.debugMode
                    fclose(app.fstreamID);
                end

                %flush serial buffer
                app.deviceFunctions.CommunicationPort(app,'flush');

                % change label on button
                app.togglebuttonStartStop.Enable=1;
                app.togglebuttonStartStop.Text='START';

                % indicate if it's inactive
                app.togglebuttonStartStop.Value=0;
                app.active=0;


            end
        end

        % Close request function: ninjaGUI20000betaUIFigure
        function fNIRS_exit(app, event)
            try
                try
                    app.deviceFunctions.SwitchLights(app,'off'); %turn lights off
                    app.deviceFunctions.Acquisition(app,'stop'); %stop acquisition
                catch
                    disp('Error when stopping device')
                end
                disp('Closing serial communication...')
                app.deviceFunctions.CommunicationPort(app,'close');
                try    %to close the LSL streamer
                    disp('Closing LSL stream...')
                    app.LSLstreamer.outlet.delete()
                catch
                    disp('Error closing LSL stream')
                end
                disp('Bye!')
                delete(app)
                return
            catch
                disp('There was some error when closing. Force closing anyway')
                delete(app)
                return
            end

        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            disp('Debug!'); %just for debugging purposes
        end

        % Menu selected function: menuLoadSDG
        function menuLoadSDGMenuSelected(app, event)
            %routine for loading the SD file and setting the system up based on
            %the file contents

            % call dialog to get file name
            disp('Please select a probe configuration file...'); % prompt user
            filtros={'*.sd;*.nsd','Source-detector file (.sd)';...
                '*.sd','Source-detector file (.sd)';...
                '*.nsd','new Source-detector file (.nsd)'};
            tryagain=1;
            %enter a loop requesting the probe file; if no valid file is
            %selected, the loop will just keep repeating until the user
            %exits
            while(tryagain)

                [shortname, path] = uigetfile(filtros,'Select probe file'); % opens explorer dialogue
                % change folder to SD file and make AutoSaveFolderPath to
                % SD folder
                if ~isequal(shortname, 0)
                    cd(path)
                    app.AutoSaveFolderPath = path;
                end

                escapeChoice = 2;

                if(shortname == 0) % will be 0 if no file selected
                    disp('No file selected.'); % prompt user
                    escapeChoice = menu('No file selected. Terminate program?', 'Yes', 'No'); % Give a loop break option
                end

                % Terminate app. This part does not work%%%%%%%%%
                if(escapeChoice == 1)
                    disp('No probe configuration selected. Program terminated.')
                    tryagain=0;
                    fNIRS_exit(app);
                    closereq
                    return
                end

                % Return to loop start
                if(shortname == 0)
                    continue
                end

                fname = [path,shortname]; % Creates full file name w/ path
                %loadErr = 0;    %will determine if there was an error when loading the probe file

                [loadRes,nSDout,SDout]=validate_nSD(app,fname);

                loadErr=~loadRes;

                % Will only exit loop once everything is alright
                if ~loadErr
                    break
                end
            end

            if(escapeChoice == 1)
                closereq
                return;
            end

            app.nSD=nSDout;

            if ~isempty(SDout)
                app.SD=SDout;
            end


            %validate that hardware is correct and compatible

            %for example, check that the number of sources and detectors on the sd file do not exceed the ones for the hardware

            % initialize LED state state variable
            app.LEDstate=zeros(app.nSD.nSrcs,app.nSD.nLambdas); % Description

            %reset current observed channel
            app.currchans=[];

            %draw the probe in appropiate window
            drawSD(app);

            %set the frequencies of each channel for state 1
            if isfield(app.nSD,'freqMap')
                %originally, the idea was that freqMap would contain a
                %frequency and state map; the dimensions of freqMap would
                %be sources, state and lambdas, and the value of the array
                %would be frequency; this idea could probably be retaken if
                %the measurement list also reflects states, but it might
                %complicate the logic and consistency of the variables
                app.deviceFunctions.StateSetup(app,app.nSD.freqMap);                
            else
                %if it was not specified in the SD file, the current
                %version will send an empty statemap and the target
                %function will deal with that
                app.deviceFunctions.StateSetup(app,[]);
            end
            

            %set the light levels for each source at state 1. Binary
            %assumes a given source will only be either turned on or off;

            if strcmp(app.deviceInformation.LaserPowerControl,'Binary')
                for k=1:app.nSD.nSrcs
                    for m=1:app.nSD.nLambdas
                        try
                            %this cycle saves in a GUI state variable the
                            %desired power level for each LED; in theory,
                            %the codification should be different for each
                            %type of LaserPowerControl, but right now they
                            %are poorly defined. So moving forward that
                            %categorical variable and the way it is handled
                            %should be reworked and made more consistent.
                            %In particular, ninja22 has 3 on power levels,
                            %where 2020 had 2 (low and high) and 2021 had
                            %only one
                            if isfield(app.nSD,'freqMap')
                                app.Level(k,m)=sign(app.nSD.freqMap(k,1,m));
                            else
                                app.Level(k,m)=1;
                            end
                        catch
                            disp('Couldn''t set the LED levels')
                        end
                    end
                end
            end

            try
                %% Initialize LSL

                addpath(genpath('liblsl-Matlab'))

                disp('Loading LSL library for selected probe...');
                lib = lsl_loadlib();

                % make a new stream outlet
                disp('Creating a new streaminfo...');
                LSLchann=length(app.nSD.measList)+app.deviceInformation.nAux+1;
                info = lsl_streaminfo(lib,'ninjaNIRS','fNIRS',LSLchann,app.deviceInformation.Rate,'cf_float32','sdfwerr32432');

                disp('Opening an outlet...');
                outlet = lsl_outlet(info);

                app.LSLstreamer.lib=lib;
                app.LSLstreamer.outlet=outlet;
                app.streaming=1;
            catch ME
                disp(['Labstreaming layer not found or not installed correctly with code, "',ME.message,'" disabling LSL'] )
            end

            %% update form based on environment/deviceinfo
            SetupForm(app);


        end

        % Menu selected function: menuExit
        function menuExitMenuSelected(app, event)
            %call closing callback
            fNIRS_exit(app);
        end

        % Menu selected function: menuSaveData
        function menuSaveDataMenuSelected(app, event)

            %save manually

            %call browse dialog
            [file,path] = uiputfile({'*.snirf','Matlab files (*.snirf)'},'Save SNIRF file',[app.deviceInformation.devID,char(datetime('now','Format','y-MM-d-HH-mm-ss')),'.snirf']);
            %call save function
            if file
                % save if file was selected
                extensionind=strfind(file,'.snirf');
                if extensionind
                    file=file(1:end-6);
                end
                save2file(app, [path,file]);
            end
        end

        % Button down function: ninjaGUI20000betaUIFigure
        function ninjaGUI20000betaUIFigureButtonDown(app, event)
            
        end

        % Value changed function: togglebuttonSrcOnOff
        function toggleSourceState(app, event)
            %function to toggle all sources at the same time
            if event.Source.Value
                % We desire all lights on at the level they are supposed to have
                app.LEDstate=ones(size(app.LEDstate)); % desired state
                for k=1:size(app.Level,1)
                    app.deviceFunctions.TurnSourceN(app,k,app.Level(k,:));                              
                    if k<=16 %for now since it only has 16 buttons for sources
                        app.(sprintf('Button_%i',k)).Value=1;
                        for m=1:size(app.Level,2)
                            app.(sprintf('togglebuttonSrc%i_%i',k,m)).Value=1;
                        end
                    end
                end
            else
                % We desire all lights off
                app.deviceFunctions.SwitchLights(app,'off');
                for k=1:size(app.Level,1)
                    if k<=16 %for now since it only has 16 buttons for sources
                        app.(sprintf('Button_%i',k)).Value=0;
                        for m=1:size(app.Level,2)
                            app.(sprintf('togglebuttonSrc%i_%i',k,m)).Value=0;
                        end
                    end
                end
                app.LEDstate=app.LEDstate*0; % save state of LEDs in memory
            end
        end

        % Value changed function: DisplayWindowsecondsEditField
        function DisplayWindowsecondsEditFieldValueChanged(app, event)
            %             value = app.DisplayWindowsecondsEditField.Value;
            %             mint=max([0,app.currt-value]); %avoids plotting negative t
            %             xlim(app.axisPlotData,[mint,app.currt])
            %
            settimeaxis(app);
        end

        % Value changed function: Button_1, Button_10, Button_11, 
        % Button_12, Button_13, Button_14, Button_15, Button_16, Button_2, 
        % Button_3, Button_4, Button_5, Button_6, Button_7, Button_8, 
        % Button_9
        function switchAllOnSource(app, event)
            %% change state of all wavelengths on clicked source
            %% read desired state of all LEDs
            for k=1:app.nSD.nSrcs
                for m=1:app.nSD.nLambdas
                    app.LEDstate(k,m)=app.(sprintf('togglebuttonSrc%i_%i',k,m)).Value;
                end
            end

            srcNmb=str2double(event.Source.Text);

            % if button state is on, all wavelengths will be on regardless
            % of previous state. if button state is off, all wavelengths
            % will be turned on regardless of previous state
            if event.Source.Value
                app.LEDstate(srcNmb,:)=1; %change state variable for this source
                % turn all on source on
                app.deviceFunctions.TurnSourceN(app,srcNmb,app.Level(srcNmb,:));                   
                %Change the GUI elements to reflect the change
                for k=1:app.deviceInformation.nLambda
                    app.(sprintf('togglebuttonSrc%i_%i',srcNmb,k)).Value=1;
                end
            else
                % turn all on source off
                app.LEDstate(srcNmb,:)=0; %change state variable for this source
                app.deviceFunctions.TurnSourceN(app,srcNmb,0*app.Level(srcNmb,:));                          
                %Change the GUI elements to reflect the change
                for k=1:app.deviceInformation.nLambda
                    app.(sprintf('togglebuttonSrc%i_%i',srcNmb,k)).Value=0;
                end
                app.togglebuttonSrcOnOff.Value=0;
            end
        end

        % Value changed function: togglebuttonSrc10_1, 
        % togglebuttonSrc10_2, togglebuttonSrc11_1, togglebuttonSrc11_2, 
        % togglebuttonSrc12_1, togglebuttonSrc12_2, togglebuttonSrc13_1, 
        % togglebuttonSrc13_2, togglebuttonSrc14_1, togglebuttonSrc14_2, 
        % togglebuttonSrc15_1, togglebuttonSrc15_2, togglebuttonSrc16_1, 
        % togglebuttonSrc16_2, togglebuttonSrc1_1, togglebuttonSrc1_2, 
        % togglebuttonSrc2_1, togglebuttonSrc2_2, togglebuttonSrc3_1, 
        % togglebuttonSrc3_2, togglebuttonSrc4_1, togglebuttonSrc4_2, 
        % togglebuttonSrc5_1, togglebuttonSrc5_2, togglebuttonSrc6_1, 
        % togglebuttonSrc6_2, togglebuttonSrc7_1, togglebuttonSrc7_2, 
        % togglebuttonSrc8_1, togglebuttonSrc8_2, togglebuttonSrc9_1, 
        % togglebuttonSrc9_2
        function switchWavelength(app, event)

            % Identify current desired state of all LEDs
            for k=1:app.nSD.nSrcs
                for m=1:app.nSD.nLambdas
                    app.LEDstate(k,m)=app.(sprintf('togglebuttonSrc%i_%i',k,m)).Value;
                end
            end

            % Identify source that originated the event
            Tag=event.Source.Tag;
            separ=strfind(Tag,'_');
            srcNmb=str2double(Tag(1:separ-1));


            estados=zeros(1,app.nSD.nLambdas);

            % identify state of other LEDs on same optode
            for m=1:app.nSD.nLambdas
                estados(m)=app.(sprintf('togglebuttonSrc%i_%i',srcNmb,m)).Value;
            end

            %send command
            app.deviceFunctions.TurnSourceN(app,srcNmb,estados.*app.Level(srcNmb,:));           

            % specify behavior of related buttons
            if ~event.Source.Value
                app.togglebuttonSrcOnOff.Value=0;
                app.(sprintf('Button_%i',srcNmb)).Value=0;
            end

        end

        % Selection changed function: DisplaytypeButtonGroup
        function DisplaytypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplaytypeButtonGroup.SelectedObject;
            setyaxis(app);


            if strcmp(selectedButton.Text,'Hemoglobin')
                app.WavelengthdisplayListBox.Value=app.WavelengthdisplayListBox.Items;
                app.WavelengthdisplayListBox.Enable=0; %disable wavelength selection
            else
                app.WavelengthdisplayListBox.Enable=1; %enable wavelength selection
            end


        end

        % Menu selected function: menu_AutoSave
        function menu_AutoSaveMenuSelected(app, event)

            % control behavior of checkmarks, as Mathworks was too lazy to
            % program it themselves
            if strcmp(app.menu_AutoSave.Checked,'on')
                app.menu_AutoSave.Checked=0;
            else
                app.menu_AutoSave.Checked=1;
            end

        end

        % Value changed function: checkboxAux1, checkboxAux2, 
        % checkboxAux3, checkboxAux4, checkboxAux5, checkboxAux6, 
        % checkboxAux7, checkboxAux8
        function selectAux2display(app, event)
            %changes the state variable that decides if specific auxiliary
            % channels are displayed during aux view
            value = app.checkboxAux1.Value;
            k=str2double(event.Source.Tag);
            app.auxDisp(k)=value;
        end

        % Value changed function: togglebuttonDisplayAux
        function togglebuttonDisplayAuxValueChanged(app, event)
            %Update the yaxis settings when the view is changed between aux
            %and regular view
            setyaxis(app);
        end

        % Menu selected function: DatastreamingMenu
        function DatastreamingMenuSelected(app, event)
            if app.DatastreamingMenu.Enable&&app.streaming
                disp('Streaming enabled')
            else
                disp('Streaming not enabled')
            end
        end

        % Menu selected function: menu_DataCollection120min, 
        % menu_DataCollection15min, menu_DataCollection180min, 
        % menu_DataCollection30min, menu_DataCollection60min
        function menu_DataCollectionMenuSelected(app, event)
            %this function is shared for all menu items for the acquisition
            %time length. Due to the way the app designer works, I will
            %determine which menu item was selected by reading the text on
            %the menu. If the text of the menus is changed this function
            %will break!
            texto=split(event.Source.Text);
            fuente=str2double(texto{1});

            for ki=[15,30,60,120,180]
                if ki~=fuente
                    app.(sprintf('menu_DataCollection%imin',ki)).Checked='off';
                else
                    app.(sprintf('menu_DataCollection%imin',ki)).Checked='on';
                end
            end
            app.nMins=fuente;

        end

        % Menu selected function: menu_AutoSaveDirectory
        function menu_AutoSaveDirectoryMenuSelected(app, event)

            app.AutoSaveFolderPath=uigetdir;
        end

        % Menu selected function: LowpassvisualizationMenu
        function LowpassvisualizationMenuSelected(app, event)
            fs=app.editRate.Value;
            app.butterFilter.order=10;
            if strcmp(app.LowpassvisualizationMenu.Checked,'on')
                app.LowpassvisualizationMenu.Checked='off';
            else
                app.LowpassvisualizationMenu.Checked='on';
                %Will filter at 8 Hz. If the frequency happened to be lower
                %than that, I will filter to 2 Hz or 1 Hz depending.
                if fs>16
                    Wn=16/fs;
                elseif fs>4
                    Wn=4/fs;
                else
                    Wn=2/fs;
                end
                [app.butterFilter.z,app.butterFilter.p,app.butterFilter.k] = butter(app.butterFilter.order,Wn);
                [app.butterFilter.b,app.butterFilter.a] = butter(3,Wn);
                app.butterFilter.sos = zp2sos(app.butterFilter.z,app.butterFilter.p,app.butterFilter.k);
            end
        end

        % Menu selected function: SDGdisplaysignallevelMenu
        function SDGdisplaysignallevelMenuSelected(app, event)
            app.SDGdisplaysignallevelMenu.Checked=~app.SDGdisplaysignallevelMenu.Checked;
            if ~app.SDGdisplaysignallevelMenu.Checked
                for cii=1:(size(app.nSD.measList,1)/2)
                    app.axesSDG.Children(end-cii+1).Color=app.levelRepresentation.color.notAnumber;
                end
            end
        end

        % Menu selected function: AcquiredarkMenu
        function AcquiredarkMenuSelected(app, event)
            %% acquire dark

            %disable GUI buttons
            disp('Starting dark acquisition')
            app.togglebuttonStartStop.Enable="off";

            %turn lights off
            app.deviceFunctions.StateSetup(app,0);

            %the way it is implemented right now is very "patchy". I'd
            %recommend finding a more elegant solution


            % make sure the incoming acquisition won't subtract dark from
            % dark
            app.deviceInformation.subtractDark=0;

            % create and open file
            fnameDark='darkState.bin';
            fileID=fopen(fnameDark,'w');

            % backup normal fID
            standardfID=app.fstreamID;

            %switch it by dark fID
            app.fstreamID=fileID;

            % turn acquisition on
            app.deviceFunctions.Acquisition(app,'start');

            % wait N seconds
            
            pause(5)

            %stop acquisition
            app.deviceFunctions.Acquisition(app,'stop');
            
            % call BytesAvailable function; this will save dark to bin
            % file, but will also return dark
            dataDark=app.deviceFunctions.ReadBytesAvailable(app);

            app.deviceInformation.darkData=mean(dataDark,2);
            
            disp('Dark acquisition finished')
            
            % if acquired successfully
            % return state to default
            app.deviceFunctions.StateSetup(app,[]);
            
            if isfield(app.nSD,'freqMap')                
                app.deviceFunctions.StateSetup(app,app.nSD.freqMap);                
            else                
                app.deviceFunctions.StateSetup(app,[]);
            end
                        
            %close file
            fclose(fileID);

            % return fID to default
            app.fstreamID=standardfID;

            % return the subtract dark flag to whatever it should be
            if app.SubtractdarkMenu.Checked
                app.deviceInformation.subtractDark=1;
            else
                app.deviceInformation.subtractDark=0;
            end

            % enable GUI buttons
            app.SubtractdarkMenu.Enable='on';        
            app.togglebuttonStartStop.Enable="on";

            
        end

        % Menu selected function: SubtractdarkMenu
        function SubtractdarkMenuSelected(app, event)
            if app.SubtractdarkMenu.Checked
                app.deviceInformation.subtractDark=0;
                app.SubtractdarkMenu.Checked="off";
            else
                app.deviceInformation.subtractDark=1;
                app.SubtractdarkMenu.Checked='on';
            end            
        end

        % Value changed function: ShowoptodeasnumbersCheckBox
        function ShowoptodeasnumbersCheckBoxValueChanged(app, event)
            value = app.ShowoptodeasnumbersCheckBox.Value;
            if value
                for u = 1:length(app.optode_text_handles)
                    set(app.optode_text_handles(u),'visible','on')
                end
                for u = 1:length(app.optode_dot_handles)
                    set(app.optode_dot_handles(u),'visible','off')
                end
            else
                for u = 1:length(app.optode_text_handles)
                    set(app.optode_text_handles(u),'visible','off')
                end
                for u = 1:length(app.optode_dot_handles)
                    set(app.optode_dot_handles(u),'visible','on')
                end
            end
        end

        % Callback function
        function LEDpowercalibrationMenuSelected(app, event)
            %app.deviceFunctions.LEDPowerCalibration(app);
            flagCalibratePowerLevel = 1;
            reportSigLevel( app, flagCalibratePowerLevel )
        end

        % Menu selected function: CalibrationGUIMenu
        function AdjustSignalLevel(app, event)
            reportSigLevel( app );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ninjaGUI20000betaUIFigure = uifigure('Visible', 'off');
            app.ninjaGUI20000betaUIFigure.Name = 'ninjaGUI 2.00.00.beta';
            app.ninjaGUI20000betaUIFigure.CloseRequestFcn = createCallbackFcn(app, @fNIRS_exit, true);
            app.ninjaGUI20000betaUIFigure.ButtonDownFcn = createCallbackFcn(app, @ninjaGUI20000betaUIFigureButtonDown, true);
            app.FileMenu = uimenu(app.ninjaGUI20000betaUIFigure);
            app.FileMenu.Text = 'File';
            app.menuLoadSDG = uimenu(app.FileMenu);
            app.menuLoadSDG.MenuSelectedFcn = createCallbackFcn(app, @menuLoadSDGMenuSelected, true);
            app.menuLoadSDG.Text = 'Load SDG';
            app.menuSaveSDG = uimenu(app.FileMenu);
            app.menuSaveSDG.Visible = 'off';
            app.menuSaveSDG.Enable = 'off';
            app.menuSaveSDG.Text = 'Save SDG';
            app.menuSaveData = uimenu(app.FileMenu);
            app.menuSaveData.MenuSelectedFcn = createCallbackFcn(app, @menuSaveDataMenuSelected, true);
            app.menuSaveData.Separator = 'on';
            app.menuSaveData.Text = 'Save data';
            app.menuExit = uimenu(app.FileMenu);
            app.menuExit.MenuSelectedFcn = createCallbackFcn(app, @menuExitMenuSelected, true);
            app.menuExit.Separator = 'on';
            app.menuExit.Text = 'Exit';
            app.OptionsMenu = uimenu(app.ninjaGUI20000betaUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.menu_AutoSave = uimenu(app.OptionsMenu);
            app.menu_AutoSave.MenuSelectedFcn = createCallbackFcn(app, @menu_AutoSaveMenuSelected, true);
            app.menu_AutoSave.Checked = 'on';
            app.menu_AutoSave.Text = 'Auto Save Data Files';
            app.menu_AutoSaveDirectory = uimenu(app.OptionsMenu);
            app.menu_AutoSaveDirectory.MenuSelectedFcn = createCallbackFcn(app, @menu_AutoSaveDirectoryMenuSelected, true);
            app.menu_AutoSaveDirectory.Text = 'Auto Save Directory';
            app.menu_DataCollectionPeriod = uimenu(app.OptionsMenu);
            app.menu_DataCollectionPeriod.Text = 'Data Collection Period';
            app.menu_DataCollection15min = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection15min.MenuSelectedFcn = createCallbackFcn(app, @menu_DataCollectionMenuSelected, true);
            app.menu_DataCollection15min.Text = '15 min';
            app.menu_DataCollection30min = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection30min.MenuSelectedFcn = createCallbackFcn(app, @menu_DataCollectionMenuSelected, true);
            app.menu_DataCollection30min.Text = '30 min ';
            app.menu_DataCollection60min = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection60min.MenuSelectedFcn = createCallbackFcn(app, @menu_DataCollectionMenuSelected, true);
            app.menu_DataCollection60min.Text = '60 min';
            app.menu_DataCollection120min = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection120min.MenuSelectedFcn = createCallbackFcn(app, @menu_DataCollectionMenuSelected, true);
            app.menu_DataCollection120min.Checked = 'on';
            app.menu_DataCollection120min.Text = '120 min';
            app.menu_DataCollection180min = uimenu(app.menu_DataCollectionPeriod);
            app.menu_DataCollection180min.MenuSelectedFcn = createCallbackFcn(app, @menu_DataCollectionMenuSelected, true);
            app.menu_DataCollection180min.Text = '180 min';
            app.menuSDGdisplaySignalLevel = uimenu(app.OptionsMenu);
            app.menuSDGdisplaySignalLevel.Visible = 'off';
            app.menuSDGdisplaySignalLevel.Enable = 'off';
            app.menuSDGdisplaySignalLevel.Text = 'SDG display signal level';
            app.menuSDGdisplaySignalLevel_Threshold = uimenu(app.menuSDGdisplaySignalLevel);
            app.menuSDGdisplaySignalLevel_Threshold.Checked = 'on';
            app.menuSDGdisplaySignalLevel_Threshold.Text = 'Threshold';
            app.menuSDGdisplaySignalLevel_Analog = uimenu(app.menuSDGdisplaySignalLevel);
            app.menuSDGdisplaySignalLevel_Analog.Text = 'Analog';
            app.DatastreamingMenu = uimenu(app.OptionsMenu);
            app.DatastreamingMenu.MenuSelectedFcn = createCallbackFcn(app, @DatastreamingMenuSelected, true);
            app.DatastreamingMenu.Text = 'Data streaming';
            app.menuStartKeypress = uimenu(app.OptionsMenu);
            app.menuStartKeypress.Visible = 'off';
            app.menuStartKeypress.Enable = 'off';
            app.menuStartKeypress.Text = 'Start with Key Press';
            app.menuStateSwitching = uimenu(app.OptionsMenu);
            app.menuStateSwitching.Visible = 'off';
            app.menuStateSwitching.Enable = 'off';
            app.menuStateSwitching.Text = 'State Switching';
            app.menuLaserLinearityCalibration = uimenu(app.OptionsMenu);
            app.menuLaserLinearityCalibration.Visible = 'off';
            app.menuLaserLinearityCalibration.Enable = 'off';
            app.menuLaserLinearityCalibration.Text = 'Laser Linearity Calibration';
            app.menuSystemCharacterization = uimenu(app.OptionsMenu);
            app.menuSystemCharacterization.Visible = 'off';
            app.menuSystemCharacterization.Enable = 'off';
            app.menuSystemCharacterization.Text = 'System Characterization';
            app.LowpassvisualizationMenu = uimenu(app.OptionsMenu);
            app.LowpassvisualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @LowpassvisualizationMenuSelected, true);
            app.LowpassvisualizationMenu.Text = 'Low-pass visualization';
            app.SDGdisplaysignallevelMenu = uimenu(app.OptionsMenu);
            app.SDGdisplaysignallevelMenu.MenuSelectedFcn = createCallbackFcn(app, @SDGdisplaysignallevelMenuSelected, true);
            app.SDGdisplaysignallevelMenu.Text = 'SDG display signal level';
            app.DarkcalibrationMenu = uimenu(app.OptionsMenu);
            app.DarkcalibrationMenu.Text = 'Dark calibration';
            app.AcquiredarkMenu = uimenu(app.DarkcalibrationMenu);
            app.AcquiredarkMenu.MenuSelectedFcn = createCallbackFcn(app, @AcquiredarkMenuSelected, true);
            app.AcquiredarkMenu.Text = 'Acquire dark';
            app.SubtractdarkMenu = uimenu(app.DarkcalibrationMenu);
            app.SubtractdarkMenu.MenuSelectedFcn = createCallbackFcn(app, @SubtractdarkMenuSelected, true);
            app.SubtractdarkMenu.Enable = 'off';
            app.SubtractdarkMenu.Text = 'Subtract dark';
            app.CalibrationGUIMenu = uimenu(app.OptionsMenu);
            app.CalibrationGUIMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustSignalLevel, true);
            app.CalibrationGUIMenu.Text = 'Calibration GUI';
            app.axisPlotData = uiaxes(app.ninjaGUI20000betaUIFigure);
            xlabel(app.axisPlotData, 'Time [s]')
            ylabel(app.axisPlotData, 'Amplitude [dB]')
            app.axesSDG = uiaxes(app.ninjaGUI20000betaUIFigure);
            app.togglebuttonStartStop = uibutton(app.ninjaGUI20000betaUIFigure, 'state');
            app.togglebuttonStartStop.ValueChangedFcn = createCallbackFcn(app, @acquisitionFunction, true);
            app.togglebuttonStartStop.Text = 'START';
            app.editStdThresh = uieditfield(app.ninjaGUI20000betaUIFigure, 'text');
            app.editStdThresh.Visible = 'off';
            app.editStdThresh.Value = '0.05';
            app.textNoiseThresh = uilabel(app.ninjaGUI20000betaUIFigure);
            app.textNoiseThresh.Visible = 'off';
            app.textNoiseThresh.Text = 'Noise Thresh';
            app.uipanelSources = uipanel(app.ninjaGUI20000betaUIFigure);
            app.uipanelSources.Title = 'SOURCES';
            app.togglebuttonSrc5_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc5_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc5_1.Text = '1';
            app.togglebuttonSrc5_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc5_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc5_2.Text = '2';
            app.togglebuttonSrc6_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc6_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc6_1.Text = '1';
            app.togglebuttonSrc6_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc6_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc6_2.Text = '2';
            app.togglebuttonSrc7_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc7_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc7_1.Text = '1';
            app.togglebuttonSrc7_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc7_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc7_2.Text = '2';
            app.togglebuttonSrc8_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc8_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc8_1.Text = '1';
            app.togglebuttonSrc8_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc8_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc8_2.Text = '2';
            app.togglebuttonSrc1_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc1_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc1_1.Text = '1';
            app.togglebuttonSrc1_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc1_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc1_2.Text = '2';
            app.togglebuttonSrc2_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc2_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc2_1.Text = '1';
            app.togglebuttonSrc2_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc2_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc2_2.Text = '2';
            app.togglebuttonSrc3_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc3_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc3_1.Text = '1';
            app.togglebuttonSrc3_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc3_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc3_2.Text = '2';
            app.togglebuttonSrc4_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc4_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc4_1.Text = '1';
            app.togglebuttonSrc4_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc4_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc4_2.Text = '2';
            app.togglebuttonSrc13_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc13_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc13_1.Text = '1';
            app.togglebuttonSrc13_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc13_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc13_2.Text = '2';
            app.togglebuttonSrc14_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc14_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc14_1.Text = '1';
            app.togglebuttonSrc14_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc14_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc14_2.Text = '2';
            app.togglebuttonSrc15_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc15_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc15_1.Text = '1';
            app.togglebuttonSrc15_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc15_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc15_2.Text = '2';
            app.togglebuttonSrc16_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc16_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc16_1.Text = '1';
            app.togglebuttonSrc16_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc16_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc16_2.Text = '2';
            app.togglebuttonSrc9_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc9_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc9_1.Text = '1';
            app.togglebuttonSrc9_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc9_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc9_2.Text = '2';
            app.togglebuttonSrc10_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc10_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc10_1.Text = '1';
            app.togglebuttonSrc10_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc10_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc10_2.Text = '2';
            app.togglebuttonSrc11_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc11_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc11_1.Text = '1';
            app.togglebuttonSrc11_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc11_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc11_2.Text = '2';
            app.togglebuttonSrc12_1 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc12_1.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc12_1.Text = '1';
            app.togglebuttonSrc12_2 = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrc12_2.ValueChangedFcn = createCallbackFcn(app, @switchWavelength, true);
            app.togglebuttonSrc12_2.Text = '2';
            app.togglebuttonSrcOnOff = uibutton(app.uipanelSources, 'state');
            app.togglebuttonSrcOnOff.ValueChangedFcn = createCallbackFcn(app, @toggleSourceState, true);
            app.togglebuttonSrcOnOff.Text = 'All On/Off';
            app.editSrcPower1 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower1.Visible = 'off';
            app.editSrcPower1.Value = '10';
            app.editSrcPower3 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower3.Visible = 'off';
            app.editSrcPower3.Value = '10';
            app.editSrcPower5 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower5.Visible = 'off';
            app.editSrcPower5.Value = '10';
            app.editSrcPower7 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower7.Visible = 'off';
            app.editSrcPower7.Value = '10';
            app.editSrcPower9 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower9.Visible = 'off';
            app.editSrcPower9.Value = '10';
            app.editSrcPower11 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower11.Visible = 'off';
            app.editSrcPower11.Value = '10';
            app.editSrcPower13 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower13.Visible = 'off';
            app.editSrcPower13.Value = '10';
            app.editSrcPower15 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower15.Visible = 'off';
            app.editSrcPower15.Value = '10';
            app.editSrcPower2 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower2.Visible = 'off';
            app.editSrcPower2.Value = '10';
            app.editSrcPower4 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower4.Visible = 'off';
            app.editSrcPower4.Value = '10';
            app.editSrcPower6 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower6.Visible = 'off';
            app.editSrcPower6.Value = '10';
            app.editSrcPower8 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower8.Visible = 'off';
            app.editSrcPower8.Value = '10';
            app.editSrcPower10 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower10.Visible = 'off';
            app.editSrcPower10.Value = '10';
            app.editSrcPower12 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower12.Visible = 'off';
            app.editSrcPower12.Value = '10';
            app.editSrcPower14 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower14.Visible = 'off';
            app.editSrcPower14.Value = '10';
            app.editSrcPower16 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower16.Visible = 'off';
            app.editSrcPower16.Value = '10';
            app.editSrcPower17 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower17.Visible = 'off';
            app.editSrcPower17.Value = '10';
            app.editSrcPower19 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower19.Visible = 'off';
            app.editSrcPower19.Value = '10';
            app.editSrcPower21 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower21.Visible = 'off';
            app.editSrcPower21.Value = '10';
            app.editSrcPower23 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower23.Visible = 'off';
            app.editSrcPower23.Value = '10';
            app.editSrcPower25 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower25.Visible = 'off';
            app.editSrcPower25.Value = '10';
            app.editSrcPower27 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower27.Visible = 'off';
            app.editSrcPower27.Value = '10';
            app.editSrcPower29 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower29.Visible = 'off';
            app.editSrcPower29.Value = '10';
            app.editSrcPower31 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower31.Visible = 'off';
            app.editSrcPower31.Value = '10';
            app.editSrcPower18 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower18.Visible = 'off';
            app.editSrcPower18.Value = '10';
            app.editSrcPower20 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower20.Visible = 'off';
            app.editSrcPower20.Value = '10';
            app.editSrcPower22 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower22.Visible = 'off';
            app.editSrcPower22.Value = '10';
            app.editSrcPower24 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower24.Visible = 'off';
            app.editSrcPower24.Value = '10';
            app.editSrcPower26 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower26.Visible = 'off';
            app.editSrcPower26.Value = '10';
            app.editSrcPower28 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower28.Visible = 'off';
            app.editSrcPower28.Value = '10';
            app.editSrcPower30 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower30.Visible = 'off';
            app.editSrcPower30.Value = '10';
            app.editSrcPower32 = uieditfield(app.uipanelSources, 'text');
            app.editSrcPower32.Visible = 'off';
            app.editSrcPower32.Value = '10';
            app.textSetSrcPower = uilabel(app.uipanelSources);
            app.textSetSrcPower.Visible = 'off';
            app.textSetSrcPower.Text = 'Auto Gain Src';
            app.editSetSrcPower = uieditfield(app.uipanelSources, 'text');
            app.editSetSrcPower.Visible = 'off';
            app.editSetSrcPower.Value = '90';
            app.pushbuttonSetSrcPower = uibutton(app.uipanelSources, 'push');
            app.pushbuttonSetSrcPower.Enable = 'off';
            app.pushbuttonSetSrcPower.Visible = 'off';
            app.pushbuttonSetSrcPower.Text = 'Set Gain';
            app.editSetSrcPowerAll = uieditfield(app.uipanelSources, 'text');
            app.editSetSrcPowerAll.Visible = 'off';
            app.editSetSrcPowerAll.Value = '50';
            app.textSetSrcPowerAll = uilabel(app.uipanelSources);
            app.textSetSrcPowerAll.Visible = 'off';
            app.textSetSrcPowerAll.Text = 'Set All Gains';
            app.Button_1 = uibutton(app.uipanelSources, 'state');
            app.Button_1.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.uipanelSources, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.uipanelSources, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.uipanelSources, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.uipanelSources, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.uipanelSources, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.uipanelSources, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.uipanelSources, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.uipanelSources, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.uipanelSources, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_10.Text = '10';
            app.Button_11 = uibutton(app.uipanelSources, 'state');
            app.Button_11.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_11.Text = '11';
            app.Button_12 = uibutton(app.uipanelSources, 'state');
            app.Button_12.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_12.Text = '12';
            app.Button_13 = uibutton(app.uipanelSources, 'state');
            app.Button_13.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_13.Text = '13';
            app.Button_14 = uibutton(app.uipanelSources, 'state');
            app.Button_14.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_14.Text = '14';
            app.Button_15 = uibutton(app.uipanelSources, 'state');
            app.Button_15.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_15.Text = '15';
            app.Button_16 = uibutton(app.uipanelSources, 'state');
            app.Button_16.ValueChangedFcn = createCallbackFcn(app, @switchAllOnSource, true);
            app.Button_16.Text = '16';
            app.uipanelDetectors = uipanel(app.ninjaGUI20000betaUIFigure);
            app.uipanelDetectors.Title = 'DETECTORS';
            app.editGainAll = uieditfield(app.uipanelDetectors, 'text');
            app.editGainAll.Value = '1';
            app.textGainAll = uilabel(app.uipanelDetectors);
            app.textGainAll.Text = 'Set All Gains';
            app.textDet1 = uilabel(app.uipanelDetectors);
            app.textDet1.Text = 'D1';
            app.textDet2 = uilabel(app.uipanelDetectors);
            app.textDet2.Text = 'D2';
            app.textDet3 = uilabel(app.uipanelDetectors);
            app.textDet3.Text = 'D3';
            app.textDet4 = uilabel(app.uipanelDetectors);
            app.textDet4.Text = 'D4';
            app.textDet5 = uilabel(app.uipanelDetectors);
            app.textDet5.Text = 'D5';
            app.textDet6 = uilabel(app.uipanelDetectors);
            app.textDet6.Text = 'D6';
            app.textDet7 = uilabel(app.uipanelDetectors);
            app.textDet7.Text = 'D7';
            app.textDet8 = uilabel(app.uipanelDetectors);
            app.textDet8.Text = 'D8';
            app.editGain1 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain1.Value = '1';
            app.editGain2 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain2.Value = '1';
            app.editGain3 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain3.Value = '1';
            app.editGain4 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain4.Value = '1';
            app.editGain5 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain5.Value = '1';
            app.editGain6 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain6.Value = '1';
            app.editGain7 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain7.Value = '1';
            app.textDet9 = uilabel(app.uipanelDetectors);
            app.textDet9.Text = 'D9';
            app.textDet10 = uilabel(app.uipanelDetectors);
            app.textDet10.Text = 'D10';
            app.textDet11 = uilabel(app.uipanelDetectors);
            app.textDet11.Text = 'D11';
            app.textDet12 = uilabel(app.uipanelDetectors);
            app.textDet12.Text = 'D12';
            app.textDet13 = uilabel(app.uipanelDetectors);
            app.textDet13.Text = 'D13';
            app.textDet14 = uilabel(app.uipanelDetectors);
            app.textDet14.Text = 'D14';
            app.textDet15 = uilabel(app.uipanelDetectors);
            app.textDet15.Text = 'D15';
            app.textDet16 = uilabel(app.uipanelDetectors);
            app.textDet16.Text = 'D16';
            app.editGain9 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain9.Value = '1';
            app.editGain10 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain10.Value = '1';
            app.editGain11 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain11.Value = '1';
            app.editGain12 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain12.Value = '1';
            app.editGain13 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain13.Value = '1';
            app.editGain14 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain14.Value = '1';
            app.editGain15 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain15.Value = '1';
            app.editGain16 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain16.Value = '1';
            app.editGain8 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain8.Value = '1';
            app.textDet17 = uilabel(app.uipanelDetectors);
            app.textDet17.Text = 'D17';
            app.textDet18 = uilabel(app.uipanelDetectors);
            app.textDet18.Text = 'D18';
            app.textDet19 = uilabel(app.uipanelDetectors);
            app.textDet19.Text = 'D19';
            app.textDet20 = uilabel(app.uipanelDetectors);
            app.textDet20.Text = 'D20';
            app.textDet21 = uilabel(app.uipanelDetectors);
            app.textDet21.Text = 'D21';
            app.textDet22 = uilabel(app.uipanelDetectors);
            app.textDet22.Text = 'D22';
            app.textDet23 = uilabel(app.uipanelDetectors);
            app.textDet23.Text = 'D23';
            app.textDet24 = uilabel(app.uipanelDetectors);
            app.textDet24.Text = 'D24';
            app.editGain17 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain17.Value = '1';
            app.editGain18 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain18.Value = '1';
            app.editGain19 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain19.Value = '1';
            app.editGain20 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain20.Value = '1';
            app.editGain21 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain21.Value = '1';
            app.editGain22 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain22.Value = '1';
            app.editGain23 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain23.Value = '1';
            app.textDet25 = uilabel(app.uipanelDetectors);
            app.textDet25.Text = 'D25';
            app.textDet26 = uilabel(app.uipanelDetectors);
            app.textDet26.Text = 'D26';
            app.textDet27 = uilabel(app.uipanelDetectors);
            app.textDet27.Text = 'D27';
            app.textDet28 = uilabel(app.uipanelDetectors);
            app.textDet28.Text = 'D28';
            app.textDet29 = uilabel(app.uipanelDetectors);
            app.textDet29.Text = 'D29';
            app.textDet30 = uilabel(app.uipanelDetectors);
            app.textDet30.Text = 'D30';
            app.textDet31 = uilabel(app.uipanelDetectors);
            app.textDet31.Text = 'D31';
            app.textDet32 = uilabel(app.uipanelDetectors);
            app.textDet32.Text = 'D32';
            app.editGain25 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain25.Value = '1';
            app.editGain26 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain26.Value = '1';
            app.editGain27 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain27.Value = '1';
            app.editGain28 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain28.Value = '1';
            app.editGain29 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain29.Value = '1';
            app.editGain30 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain30.Value = '1';
            app.editGain31 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain31.Value = '1';
            app.editGain32 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain32.Value = '1';
            app.editGain24 = uieditfield(app.uipanelDetectors, 'text');
            app.editGain24.Value = '1';
            app.textSetGain = uilabel(app.uipanelDetectors);
            app.textSetGain.Text = 'Auto Gain Det';
            app.editAutoGainLevel = uieditfield(app.uipanelDetectors, 'text');
            app.editAutoGainLevel.Value = '120';
            app.pushbuttonSetGain = uibutton(app.uipanelDetectors, 'push');
            app.pushbuttonSetGain.Enable = 'off';
            app.pushbuttonSetGain.Text = 'Set Gain';
            app.pushbuttonCheckSaturation = uibutton(app.uipanelDetectors, 'push');
            app.pushbuttonCheckSaturation.Enable = 'off';
            app.pushbuttonCheckSaturation.Text = 'Check Saturation';
            app.togglebuttonSystemCharacterize = uibutton(app.ninjaGUI20000betaUIFigure, 'state');
            app.togglebuttonSystemCharacterize.Visible = 'off';
            app.togglebuttonSystemCharacterize.Text = 'START Characterization';
            app.togglebuttonReset = uibutton(app.ninjaGUI20000betaUIFigure, 'push');
            app.togglebuttonReset.Text = 'Reset Connection';
            app.uipanelStateSwitching = uibuttongroup(app.ninjaGUI20000betaUIFigure);
            app.uipanelStateSwitching.Title = 'STATE SWITCHING';
            app.checkboxSrcSwitchEnable = uicheckbox(app.uipanelStateSwitching);
            app.checkboxSrcSwitchEnable.Text = 'Enable';
            app.radiobuttonState3 = uiradiobutton(app.uipanelStateSwitching);
            app.radiobuttonState3.Text = 'State 3';
            app.radiobuttonState1 = uiradiobutton(app.uipanelStateSwitching);
            app.radiobuttonState1.Text = 'State 1';
            app.radiobuttonState1.Value = true;
            app.radiobuttonState2 = uiradiobutton(app.uipanelStateSwitching);
            app.radiobuttonState2.Text = 'State 2';
            app.editSrcSwitchDwell1 = uieditfield(app.uipanelStateSwitching, 'text');
            app.editSrcSwitchDwell1.Value = '10';
            app.editSrcSwitchDwell2 = uieditfield(app.uipanelStateSwitching, 'text');
            app.editSrcSwitchDwell2.Value = '10';
            app.editSrcSwitchDwell3 = uieditfield(app.uipanelStateSwitching, 'text');
            app.editSrcSwitchDwell3.Value = '10';
            app.textSrcSwitchDwell = uilabel(app.uipanelStateSwitching);
            app.textSrcSwitchDwell.Text = 'Dwell (#rec)';
            app.pushbuttonLoadStates = uibutton(app.uipanelStateSwitching, 'push');
            app.pushbuttonLoadStates.Text = 'Load State';
            app.uipanel5 = uipanel(app.ninjaGUI20000betaUIFigure);
            app.uipanel5.Title = 'DISPLAY';
            app.WavelengthdisplayListBoxLabel = uilabel(app.uipanel5);
            app.WavelengthdisplayListBoxLabel.Text = {'Wavelength'; 'display'};
            app.WavelengthdisplayListBox = uilistbox(app.uipanel5);
            app.WavelengthdisplayListBox.Items = {};
            app.WavelengthdisplayListBox.Multiselect = 'on';
            app.WavelengthdisplayListBox.Value = {};
            app.DisplayWindowsecondsEditFieldLabel = uilabel(app.uipanel5);
            app.DisplayWindowsecondsEditFieldLabel.Text = 'Display Window (seconds)';
            app.DisplayWindowsecondsEditField = uieditfield(app.uipanel5, 'numeric');
            app.DisplayWindowsecondsEditField.Limits = [0 300];
            app.DisplayWindowsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayWindowsecondsEditFieldValueChanged, true);
            app.DisplayWindowsecondsEditField.Value = 10;
            app.yminEditFieldLabel = uilabel(app.uipanel5);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.uipanel5, 'numeric');
            app.yminEditField.Value = 20;
            app.ymaxEditFieldLabel = uilabel(app.uipanel5);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.uipanel5, 'numeric');
            app.ymaxEditField.Value = 100;
            app.checkboxAutoscale = uicheckbox(app.uipanel5);
            app.checkboxAutoscale.Text = 'Autoscale y';
            app.checkboxAutoscale.Value = true;
            app.DisplaytypeButtonGroup = uibuttongroup(app.uipanel5);
            app.DisplaytypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplaytypeButtonGroupSelectionChanged, true);
            app.DisplaytypeButtonGroup.Title = 'Display type';
            app.dBButton = uiradiobutton(app.DisplaytypeButtonGroup);
            app.dBButton.Text = 'dB';
            app.dBButton.Value = true;
            app.DeltaODButton = uiradiobutton(app.DisplaytypeButtonGroup);
            app.DeltaODButton.Text = 'Delta OD';
            app.HemoglobinButton = uiradiobutton(app.DisplaytypeButtonGroup);
            app.HemoglobinButton.Text = 'Hemoglobin';
            app.uipanelAux = uipanel(app.ninjaGUI20000betaUIFigure);
            app.uipanelAux.Title = 'AUXILIARIES';
            app.checkboxAux1 = uicheckbox(app.uipanelAux);
            app.checkboxAux1.ValueChangedFcn = createCallbackFcn(app, @selectAux2display, true);
            app.checkboxAux1.Text = '';
            app.checkboxAux1.Value = true;
            app.checkboxAux2 = uicheckbox(app.uipanelAux);
            app.checkboxAux2.ValueChangedFcn = createCallbackFcn(app, @selectAux2display, true);
            app.checkboxAux2.Text = '';
            app.checkboxAux2.Value = true;
            app.checkboxAux3 = uicheckbox(app.uipanelAux);
            app.checkboxAux3.ValueChangedFcn = createCallbackFcn(app, @selectAux2display, true);
            app.checkboxAux3.Text = '';
            app.checkboxAux3.Value = true;
            app.checkboxAux4 = uicheckbox(app.uipanelAux);
            app.checkboxAux4.ValueChangedFcn = createCallbackFcn(app, @selectAux2display, true);
            app.checkboxAux4.Text = '';
            app.checkboxAux4.Value = true;
            app.checkboxAux5 = uicheckbox(app.uipanelAux);
            app.checkboxAux5.ValueChangedFcn = createCallbackFcn(app, @selectAux2display, true);
            app.checkboxAux5.Text = '';
            app.checkboxAux5.Value = true;
            app.checkboxAux6 = uicheckbox(app.uipanelAux);
            app.checkboxAux6.ValueChangedFcn = createCallbackFcn(app, @selectAux2display, true);
            app.checkboxAux6.Text = '';
            app.checkboxAux6.Value = true;
            app.checkboxAux7 = uicheckbox(app.uipanelAux);
            app.checkboxAux7.ValueChangedFcn = createCallbackFcn(app, @selectAux2display, true);
            app.checkboxAux7.Text = '';
            app.checkboxAux7.Value = true;
            app.checkboxAux8 = uicheckbox(app.uipanelAux);
            app.checkboxAux8.ValueChangedFcn = createCallbackFcn(app, @selectAux2display, true);
            app.checkboxAux8.Text = '';
            app.checkboxAux8.Value = true;
            app.popupmenuAux8 = uidropdown(app.uipanelAux);
            app.popupmenuAux8.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux8.Enable = 'off';
            app.popupmenuAux8.Value = 'Pulse Ox raw';
            app.popupmenuAux7 = uidropdown(app.uipanelAux);
            app.popupmenuAux7.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux7.Enable = 'off';
            app.popupmenuAux7.Value = 'Pulse Ox raw';
            app.popupmenuAux6 = uidropdown(app.uipanelAux);
            app.popupmenuAux6.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux6.Enable = 'off';
            app.popupmenuAux6.Value = 'Pulse Ox raw';
            app.popupmenuAux5 = uidropdown(app.uipanelAux);
            app.popupmenuAux5.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux5.Enable = 'off';
            app.popupmenuAux5.Value = 'Pulse Ox raw';
            app.popupmenuAux4 = uidropdown(app.uipanelAux);
            app.popupmenuAux4.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux4.Enable = 'off';
            app.popupmenuAux4.Value = 'Pulse Ox raw';
            app.popupmenuAux3 = uidropdown(app.uipanelAux);
            app.popupmenuAux3.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux3.Enable = 'off';
            app.popupmenuAux3.Value = 'Pulse Ox raw';
            app.popupmenuAux2 = uidropdown(app.uipanelAux);
            app.popupmenuAux2.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux2.Enable = 'off';
            app.popupmenuAux2.Value = 'Pulse Ox raw';
            app.popupmenuAux1 = uidropdown(app.uipanelAux);
            app.popupmenuAux1.Items = {'Pulse Ox raw', 'Pulse Ox HR', 'Pulse Ox SaO2', 'Respiration Strain Gauge', 'BP cuff'};
            app.popupmenuAux1.Enable = 'off';
            app.popupmenuAux1.Value = 'Pulse Ox raw';
            app.togglebuttonDisplayAux = uibutton(app.uipanelAux, 'state');
            app.togglebuttonDisplayAux.ValueChangedFcn = createCallbackFcn(app, @togglebuttonDisplayAuxValueChanged, true);
            app.togglebuttonDisplayAux.Text = 'Display Aux';
            app.togglebuttonSystemCharTime = uibutton(app.ninjaGUI20000betaUIFigure, 'state');
            app.togglebuttonSystemCharTime.Text = 'START Charact. Time';
            app.pushbuttonStimMark = uibutton(app.ninjaGUI20000betaUIFigure, 'push');
            app.pushbuttonStimMark.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonStimMarkButtonPushed, true);
            app.pushbuttonStimMark.Text = 'Stim Mark';
            app.textAutoGainSrcDet = uilabel(app.ninjaGUI20000betaUIFigure);
            app.textAutoGainSrcDet.Text = 'Auto Gain Src/Det';
            app.editAutoGainSrcDet = uieditfield(app.ninjaGUI20000betaUIFigure, 'text');
            app.editAutoGainSrcDet.Value = '120';
            app.pushbuttonAutoGainSrcDet = uibutton(app.ninjaGUI20000betaUIFigure, 'push');
            app.pushbuttonAutoGainSrcDet.Enable = 'off';
            app.pushbuttonAutoGainSrcDet.Text = 'Set Gain';
            app.textMessage = uilabel(app.ninjaGUI20000betaUIFigure);
            app.textMessage.Text = '';
            app.textSDGfilename = uilabel(app.ninjaGUI20000betaUIFigure);
            app.textSDGfilename.Text = '';
            app.DebugButton = uibutton(app.ninjaGUI20000betaUIFigure, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.RateEditFieldLabel = uilabel(app.ninjaGUI20000betaUIFigure);
            app.RateEditFieldLabel.Text = 'Rate';
            app.editRate = uieditfield(app.ninjaGUI20000betaUIFigure, 'numeric');
            app.editRate.Limits = [0 Inf];
            app.editRate.Value = 25;
            app.displayCheckBox = uicheckbox(app.ninjaGUI20000betaUIFigure);
            app.displayCheckBox.Text = 'Plot data';
            app.displayCheckBox.Value = true;
            app.ShowoptodeasnumbersCheckBox = uicheckbox(app.ninjaGUI20000betaUIFigure);
            app.ShowoptodeasnumbersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowoptodeasnumbersCheckBoxValueChanged, true);
            app.ShowoptodeasnumbersCheckBox.Text = 'Show optode as numbers';
            % Show the figure after all components are created
            app.ninjaGUI20000betaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fNIRSapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ninjaGUI20000betaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ninjaGUI20000betaUIFigure)
        end
    end
end