classdef hardwareselect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelecthardwareUIFigure          matlab.ui.Figure
        ThreshhighdBEditField           matlab.ui.control.NumericEditField
        ThreshhighdBEditFieldLabel      matlab.ui.control.Label
        ThreshlowdBEditField            matlab.ui.control.NumericEditField
        ThreshlowdBEditFieldLabel       matlab.ui.control.Label
        FilenameforcfgfileEditField     matlab.ui.control.EditField
        FilenameforcfgfileEditFieldLabel  matlab.ui.control.Label
        AuxiliaryportlabelsPanel        matlab.ui.container.Panel
        Aux8EditField                   matlab.ui.control.EditField
        Aux8EditFieldLabel              matlab.ui.control.Label
        Aux7EditField                   matlab.ui.control.EditField
        Aux7EditFieldLabel              matlab.ui.control.Label
        Aux6EditField                   matlab.ui.control.EditField
        Aux6EditFieldLabel              matlab.ui.control.Label
        Aux5EditField                   matlab.ui.control.EditField
        Aux5EditFieldLabel              matlab.ui.control.Label
        Aux4EditField                   matlab.ui.control.EditField
        Aux4EditFieldLabel              matlab.ui.control.Label
        Aux3EditField                   matlab.ui.control.EditField
        Aux3EditFieldLabel              matlab.ui.control.Label
        Aux2EditField                   matlab.ui.control.EditField
        Aux2EditFieldLabel              matlab.ui.control.Label
        Aux1EditField                   matlab.ui.control.EditField
        Aux1EditFieldLabel              matlab.ui.control.Label
        NumberofauxiliaryportsEditField  matlab.ui.control.NumericEditField
        NumberofauxiliaryportsEditFieldLabel  matlab.ui.control.Label
        Wavelength4nmEditField          matlab.ui.control.NumericEditField
        Wavelength4nmEditFieldLabel     matlab.ui.control.Label
        Wavelength3nmEditField          matlab.ui.control.NumericEditField
        Wavelength3nmEditFieldLabel     matlab.ui.control.Label
        Wavelength2nmEditField          matlab.ui.control.NumericEditField
        Wavelength2nmEditFieldLabel     matlab.ui.control.Label
        Wavelength1nmEditField          matlab.ui.control.NumericEditField
        Wavelength1nmEditFieldLabel     matlab.ui.control.Label
        NumberofwavelengthsEditField    matlab.ui.control.NumericEditField
        NumberofwavelengthsEditFieldLabel  matlab.ui.control.Label
        SamplingrateEditField           matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel      matlab.ui.control.Label
        AdjustablesamplingrateCheckBox  matlab.ui.control.CheckBox
        AdjustablegainCheckBox          matlab.ui.control.CheckBox
        LaserPowercontrolDropDown       matlab.ui.control.DropDown
        LaserPowercontrolDropDownLabel  matlab.ui.control.Label
        NumberofdetectorsEditField      matlab.ui.control.NumericEditField
        NumberofdetectorsEditFieldLabel  matlab.ui.control.Label
        NumberofsourcesEditField        matlab.ui.control.NumericEditField
        NumberofsourcesEditFieldLabel   matlab.ui.control.Label
        DoneButton                      matlab.ui.control.Button
        PortSelection                   matlab.ui.control.DropDown
        SerialportDropDownLabel         matlab.ui.control.Label
        DeviceSelection                 matlab.ui.control.DropDown
        DevicetypeLabel                 matlab.ui.control.Label
    end


    properties (Access = private)

        %maindlg % Need to store the main dialog here for things to work, due to current limitations of app builder
        device=struct('devID',nan,...
            'commPort',nan, ...
            'auxList',nan, ...
            'nSrcs',nan, ...
            'nDets',nan, ...
            'LaserPowerControl',nan, ...
            'AdjustableGain',nan, ...
            'AdjustableRate',nan, ...
            'Rate',nan, ...
            'nLambda',nan, ...
            'Wavelengths',nan, ...
            'nAux',nan) % Saves all the fields that will go to devinfo

        cfgfname='default'; % Description

    end

    properties (Access = public)
        result=0; % Stores the result of the dialog
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.SelecthardwareUIFigure.Visible='off';
            movegui(app.SelecthardwareUIFigure,"center")
            app.SelecthardwareUIFigure.Visible='on';
            try
                compDevices = checkCompatibility();
                %app.DeviceSelection.Items=[' ';importdata('hwcompatlist.txt')];
                app.DeviceSelection.Items=[' ';compDevices'];
                app.device.devID = app.DeviceSelection.Value;
            catch ME
                msgbox(ME.identifier)
                app.result=0;
                app.endFcn(app)
                return
            end
            app.PortSelection.Items=seriallist;
            app.device.commPort = app.PortSelection.Value;
        end

        % Value changed function: PortSelection
        function PortSelectionValueChanged(app, event)
            app.PortSelection.Items=seriallist;
            app.device.commPort = app.PortSelection.Value;

        end

        % Close request function: SelecthardwareUIFigure
        function endFcn(app, event)
            resultado=app.result;
            if ~resultado
                %error and close program
                msgbox('Configuration was not successful')
                delete(app)
            else
                %call the  fNIRS program again
                disp('Saved configuration file')
                configFileName=app.cfgfname;
                delete(app)
                %ninjaGUI(configFileName)
            end

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %make sure I read the auxiliary field names
            if isnan(app.device.nAux)
                app.device.nAux=app.NumberofauxiliaryportsEditField.Value;
            end
            app.device.auxList=cell(1,app.device.nAux);
            for ki=1:app.device.nAux
                app.device.auxList{ki}=app.(sprintf('Aux%iEditField',ki)).Value;
            end

            app.device.nSrcs=app.NumberofsourcesEditField.Value;
            app.device.nDets=app.NumberofdetectorsEditField.Value;

            %validate fields, if things are wrong, set result as 0
            if isempty(app.device.commPort)
                %error message
                msgbox('No serial port selected')
                app.result=0;
            else
                %parse values and save into structure
                devinfo=app.device;

                %check that all values have been assigned
                campos=fieldnames(devinfo);
                for ki=1:length(campos)
                    try
                        if ~strcmp(campos{ki},'auxList') %skip the aux list because it could contain no elements
                            condi=all(isnan(devinfo.(campos{ki})));
                        end                        
                    catch
                        condi=0;
                    end

                    if condi
                        disp('Not all fields have been assigned')
                        app.result=0;
                        break;
                    end
                    app.result=1;
                end

                if app.result
                    try
                        %write cfg file
                        save([app.FilenameforcfgfileEditField.Value,'.cfg'],"devinfo",'-mat')
                        app.result=1;
                        app.cfgfname=[app.FilenameforcfgfileEditField.Value,'.cfg'];
                    catch
                        app.result=0;
                    end
                else
                    msg = 'Configuration not complete yet. Try again or close configuration app?';
                    title = 'Warning';
                    selection = uiconfirm(app.SelecthardwareUIFigure,msg,title,...
                        'Icon','warning',...
                        'Options',{'Try again','Close application'},...
                        'DefaultOption',1,'CancelOption',2);
                    switch selection
                        case 'Try again'
                            return
                    end
                end

            end
            %end the function anyway
            app.endFcn(app);
            return
        end

        % Value changed function: DeviceSelection
        function DeviceSelectionValueChanged(app, event)
            app.device.devID = app.DeviceSelection.Value;

            switch app.device.devID
                case 'ninjaNIRS2024'
                    %maybe these fixed values should be read from some
                    %configuration file instead of being hard coded here
                    app.device.LaserPowerControl='Binary'; %<- not really: 22 has 3 on levels, but leave it like this
                    app.device.Rate=125;  %<- it changes, it is 1000/N where N is the number of states; leave it like this for now, figure out how to best do it later; that said, for full head the rate will be fixed probably to 7.8 Hz at first
                    app.device.nLambda=2;
                    app.device.Wavelengths=[760,850];  %confirm!
                    app.device.AdjustableGain=0;
                    app.device.AdjustableRate=0;  %<- not adjustable but will change depending on state map; the one I will use to prototype has 8 states
                    app.NumberofauxiliaryportsEditField.Value=0; %<- no auxiliaries in the beta
                    app.device.nAux=app.NumberofauxiliaryportsEditField.Value;                    

                    app.ThreshlowdBEditField.Value=110; %unknown, do not use for now
                    app.ThreshhighdBEditField.Value=160;  %unknown, do not use for now

                    app.device.levelRepresentation.thresholds(1)=app.ThreshlowdBEditField.Value;
                    app.device.levelRepresentation.thresholds(2)=app.ThreshhighdBEditField.Value;

                    app.device.optodeSaturationThreshold=5000;  %not sure if this is the right value for this, used to determine if dark state is too high

                    app.Aux1EditField.Value='NONE';
                    app.Aux2EditField.Value='NONE';
                    app.Aux3EditField.Value='AccX';
                    app.Aux4EditField.Value='AccY';
                    app.Aux5EditField.Value='AccZ';
                    app.Aux6EditField.Value='Temperature';
                    app.Aux7EditField.Value='RemoteTrig';

                    app.NumberofsourcesEditField.Enable="on";   %only two out of the four work now, but assume the four work to make the logic simpler
                    app.NumberofsourcesEditField.Value=4;
                    app.NumberofdetectorsEditField.Enable="on";  %8... I think?
                    app.NumberofdetectorsEditField.Value=16;
                    app.LaserPowercontrolDropDown.Editable=0; %it is actually editable for 22, but 3 levels; furthermore, it probably should not be changed at runtime
                    app.LaserPowercontrolDropDown.Value=app.device.LaserPowerControl;
                    app.SamplingrateEditField.Editable=app.device.AdjustableRate;
                    app.SamplingrateEditField.Value=app.device.Rate;
                    app.NumberofwavelengthsEditField.Editable=0;
                    app.NumberofwavelengthsEditField.Value=app.device.nLambda;
                    app.Wavelength1nmEditField.Visible=1;
                    app.Wavelength1nmEditField.Editable=0;
                    app.Wavelength1nmEditField.Value=app.device.Wavelengths(1);
                    app.Wavelength2nmEditField.Visible=1;
                    app.Wavelength2nmEditField.Editable=0;
                    app.Wavelength2nmEditField.Value=app.device.Wavelengths(2);
                    app.Wavelength3nmEditField.Visible=0;
                    app.Wavelength3nmEditField.Editable=0;
                    app.Wavelength4nmEditField.Visible=0;
                    app.Wavelength4nmEditField.Editable=0;
                    app.NumberofauxiliaryportsEditField.Enable=0;
                    app.AdjustablesamplingrateCheckBox.Enable=0;
                    app.AdjustablesamplingrateCheckBox.Value=app.device.AdjustableRate;
                    app.AdjustablegainCheckBox.Enable=0;
                    app.AdjustablegainCheckBox.Value=app.device.AdjustableGain;

                    %these four fields are new to the config file starting
                    %2022. The GUI should be backwards compatible and
                    %handle the absence of these in the config file
                    app.device.controllableSources=0; %this property will be used to restrict control of the sources to the user
                    app.device.controllableIndividualSources=0;  %for disabling the individual source controls. I am setting it as zero as I am not sure if it will be practical for 22. Separate from the previos property assuming that maybe you cannot do one source but can do all of them
                    app.device.controlSourcesDuringAcquisition=0;  %even if the user can control the sources, there may be devices for which switching the lights mid acquisition might break things
                    app.device.stateMap=0;  %could be used to store the state map, still not sure of how the final implementation will look like

                    app.FilenameforcfgfileEditField.Enable=1;
                    app.DoneButton.Enable=1;

                    for ki=1:8
                        if ki<=app.device.nAux
                            app.(sprintf('Aux%iEditField',ki)).Enable=1;
                        end
                    end


                case 'ninjaNIRS2022'
                    %maybe these fixed values should be read from some
                    %configuration file instead of being hard coded here
                    app.device.LaserPowerControl='Binary'; %<- not really: 22 has 3 on levels, but leave it like this
                    app.device.Rate=125;  %<- it changes, it is 1000/N where N is the number of states; leave it like this for now, figure out how to best do it later; that said, for full head the rate will be fixed probably to 7.8 Hz at first
                    app.device.nLambda=2;
                    app.device.Wavelengths=[730,850];  %confirm!
                    app.device.AdjustableGain=0;
                    app.device.AdjustableRate=0;  %<- not adjustable but will change depending on state map; the one I will use to prototype has 8 states
                    app.NumberofauxiliaryportsEditField.Value=0; %<- no auxiliaries in the beta
                    app.device.nAux=app.NumberofauxiliaryportsEditField.Value;                    

                    app.ThreshlowdBEditField.Value=110; %unknown, do not use for now
                    app.ThreshhighdBEditField.Value=160;  %unknown, do not use for now

                    app.device.levelRepresentation.thresholds(1)=app.ThreshlowdBEditField.Value;
                    app.device.levelRepresentation.thresholds(2)=app.ThreshhighdBEditField.Value;

                    app.device.optodeSaturationThreshold=5000;  %not sure if this is the right value for this, used to determine if dark state is too high

                    app.Aux1EditField.Value='NONE';
                    app.Aux2EditField.Value='NONE';
                    app.Aux3EditField.Value='AccX';
                    app.Aux4EditField.Value='AccY';
                    app.Aux5EditField.Value='AccZ';
                    app.Aux6EditField.Value='Temperature';
                    app.Aux7EditField.Value='RemoteTrig';

                    app.NumberofsourcesEditField.Enable="on";   %only two out of the four work now, but assume the four work to make the logic simpler
                    app.NumberofsourcesEditField.Value=4;
                    app.NumberofdetectorsEditField.Enable="on";  %8... I think?
                    app.NumberofdetectorsEditField.Value=16;
                    app.LaserPowercontrolDropDown.Editable=0; %it is actually editable for 22, but 3 levels; furthermore, it probably should not be changed at runtime
                    app.LaserPowercontrolDropDown.Value=app.device.LaserPowerControl;
                    app.SamplingrateEditField.Editable=app.device.AdjustableRate;
                    app.SamplingrateEditField.Value=app.device.Rate;
                    app.NumberofwavelengthsEditField.Editable=0;
                    app.NumberofwavelengthsEditField.Value=app.device.nLambda;
                    app.Wavelength1nmEditField.Visible=1;
                    app.Wavelength1nmEditField.Editable=0;
                    app.Wavelength1nmEditField.Value=app.device.Wavelengths(1);
                    app.Wavelength2nmEditField.Visible=1;
                    app.Wavelength2nmEditField.Editable=0;
                    app.Wavelength2nmEditField.Value=app.device.Wavelengths(2);
                    app.Wavelength3nmEditField.Visible=0;
                    app.Wavelength3nmEditField.Editable=0;
                    app.Wavelength4nmEditField.Visible=0;
                    app.Wavelength4nmEditField.Editable=0;
                    app.NumberofauxiliaryportsEditField.Enable=0;
                    app.AdjustablesamplingrateCheckBox.Enable=0;
                    app.AdjustablesamplingrateCheckBox.Value=app.device.AdjustableRate;
                    app.AdjustablegainCheckBox.Enable=0;
                    app.AdjustablegainCheckBox.Value=app.device.AdjustableGain;

                    %these four fields are new to the config file starting
                    %2022. The GUI should be backwards compatible and
                    %handle the absence of these in the config file
                    app.device.controllableSources=0; %this property will be used to restrict control of the sources to the user
                    app.device.controllableIndividualSources=0;  %for disabling the individual source controls. I am setting it as zero as I am not sure if it will be practical for 22. Separate from the previos property assuming that maybe you cannot do one source but can do all of them
                    app.device.controlSourcesDuringAcquisition=0;  %even if the user can control the sources, there may be devices for which switching the lights mid acquisition might break things
                    app.device.stateMap=0;  %could be used to store the state map, still not sure of how the final implementation will look like

                    app.FilenameforcfgfileEditField.Enable=1;
                    app.DoneButton.Enable=1;

                    for ki=1:8
                        if ki<=app.device.nAux
                            app.(sprintf('Aux%iEditField',ki)).Enable=1;
                        end
                    end


                case 'ninjaNIRS2021a'
                    %maybe these fixed values should be read from some
                    %configuration file instead of being hard coded here
                    app.device.LaserPowerControl='Binary';
                    app.device.Rate=25;
                    app.device.nLambda=2;
                    app.device.Wavelengths=[730,850];
                    app.device.AdjustableGain=0;
                    app.device.AdjustableRate=0;
                    app.NumberofauxiliaryportsEditField.Value=7;
                    app.device.nAux=app.NumberofauxiliaryportsEditField.Value;

                    app.ThreshlowdBEditField.Value=110;
                    app.ThreshhighdBEditField.Value=160;

                    app.device.levelRepresentation.thresholds(1)=app.ThreshlowdBEditField.Value;
                    app.device.levelRepresentation.thresholds(2)=app.ThreshhighdBEditField.Value;

                    app.device.optodeSaturationThreshold=500000;

                    app.Aux1EditField.Value='NONE';
                    app.Aux2EditField.Value='NONE';
                    app.Aux3EditField.Value='AccX';
                    app.Aux4EditField.Value='AccY';
                    app.Aux5EditField.Value='AccZ';
                    app.Aux6EditField.Value='Temperature';
                    app.Aux7EditField.Value='RemoteTrig';

                    app.NumberofsourcesEditField.Enable=1;
                    app.NumberofdetectorsEditField.Enable=1;
                    app.LaserPowercontrolDropDown.Editable=0;
                    app.LaserPowercontrolDropDown.Value=app.device.LaserPowerControl;
                    app.SamplingrateEditField.Editable=0;
                    app.SamplingrateEditField.Value=app.device.Rate;
                    app.NumberofwavelengthsEditField.Editable=0;
                    app.NumberofwavelengthsEditField.Value=app.device.nLambda;
                    app.Wavelength1nmEditField.Visible=1;
                    app.Wavelength1nmEditField.Editable=0;
                    app.Wavelength1nmEditField.Value=app.device.Wavelengths(1);
                    app.Wavelength2nmEditField.Visible=1;
                    app.Wavelength2nmEditField.Editable=0;
                    app.Wavelength2nmEditField.Value=app.device.Wavelengths(2);
                    app.Wavelength3nmEditField.Visible=0;
                    app.Wavelength3nmEditField.Editable=0;
                    app.Wavelength4nmEditField.Visible=0;
                    app.Wavelength4nmEditField.Editable=0;
                    app.NumberofauxiliaryportsEditField.Enable=0;
                    app.AdjustablesamplingrateCheckBox.Enable=0;
                    app.AdjustablesamplingrateCheckBox.Value=app.device.AdjustableRate;
                    app.AdjustablegainCheckBox.Enable=0;
                    app.AdjustablegainCheckBox.Value=app.device.AdjustableGain;

                    app.FilenameforcfgfileEditField.Enable=1;
                    app.DoneButton.Enable=1;

                    for ki=1:8
                        if ki<=app.device.nAux
                            app.(sprintf('Aux%iEditField',ki)).Enable=1;
                        end
                    end


                case 'ninjaNIRS2020'
                    %maybe these fixed values should be read from some
                    %configuration file instead of being hard coded here
                    app.device.LaserPowerControl='Binary';
                    app.device.Rate=93;
                    app.device.nLambda=2;
                    app.device.Wavelengths=[730,850];
                    app.device.AdjustableGain=0;
                    app.device.AdjustableRate=0;
                    app.NumberofauxiliaryportsEditField.Value=5;
                    app.device.nAux=app.NumberofauxiliaryportsEditField.Value;

                    app.ThreshlowdBEditField.Value=110;
                    app.ThreshhighdBEditField.Value=160;

                    app.device.levelRepresentation.thresholds(1)=app.ThreshlowdBEditField.Value;
                    app.device.levelRepresentation.thresholds(2)=app.ThreshhighdBEditField.Value;

                    app.Aux1EditField.Value='AccX';
                    app.Aux2EditField.Value='AccY';
                    app.Aux3EditField.Value='AccZ';
                    app.Aux4EditField.Value='Temperature';
                    app.Aux5EditField.Value='Trigger';

                    app.NumberofsourcesEditField.Enable=1;
                    app.NumberofdetectorsEditField.Enable=1;
                    app.LaserPowercontrolDropDown.Editable=0;
                    app.LaserPowercontrolDropDown.Value=app.device.LaserPowerControl;
                    app.SamplingrateEditField.Editable=0;
                    app.SamplingrateEditField.Value=app.device.Rate;
                    app.NumberofwavelengthsEditField.Editable=0;
                    app.NumberofwavelengthsEditField.Value=app.device.nLambda;
                    app.Wavelength1nmEditField.Visible=1;
                    app.Wavelength1nmEditField.Editable=0;
                    app.Wavelength1nmEditField.Value=app.device.Wavelengths(1);
                    app.Wavelength2nmEditField.Visible=1;
                    app.Wavelength2nmEditField.Editable=0;
                    app.Wavelength2nmEditField.Value=app.device.Wavelengths(2);
                    app.Wavelength3nmEditField.Visible=0;
                    app.Wavelength3nmEditField.Editable=0;
                    app.Wavelength4nmEditField.Visible=0;
                    app.Wavelength4nmEditField.Editable=0;
                    app.NumberofauxiliaryportsEditField.Enable=0;
                    app.AdjustablesamplingrateCheckBox.Enable=0;
                    app.AdjustablesamplingrateCheckBox.Value=app.device.AdjustableRate;
                    app.AdjustablegainCheckBox.Enable=0;
                    app.AdjustablegainCheckBox.Value=app.device.AdjustableGain;

                    app.FilenameforcfgfileEditField.Enable=1;
                    app.DoneButton.Enable=1;

                    for ki=1:8
                        if ki<=app.device.nAux
                            app.(sprintf('Aux%iEditField',ki)).Enable=0;
                        end
                    end


                case 'NIRS1k'
                    app.device.nSrcs=4;
                    app.device.nDets=12;
                    app.device.LaserPowerControl='Binary';
                    app.device.Rate=250;
                    app.device.nLambda=2;
                    app.device.Wavelengths=[730,850];
                    app.device.AdjustableGain=0;
                    app.device.AdjustableRate=0;
                    app.device.nAux=5;

                    app.ThreshlowdBEditField.Value=110;
                    app.ThreshhighdBEditField.Value=160;

                    app.device.levelRepresentation.thresholds(1)=app.ThreshlowdBEditField.Value;
                    app.device.levelRepresentation.thresholds(2)=app.ThreshhighdBEditField.Value;

                    app.NumberofsourcesEditField.Enable=0;
                    app.NumberofsourcesEditField.Value=app.device.nSrcs;
                    app.NumberofdetectorsEditField.Enable=0;
                    app.NumberofdetectorsEditField.Value=app.device.nDets;
                    app.LaserPowercontrolDropDown.Editable=0;
                    app.LaserPowercontrolDropDown.Value=app.device.LaserPowerControl;
                    app.SamplingrateEditField.Editable=0;
                    app.SamplingrateEditField.Value=app.device.Rate;
                    app.NumberofwavelengthsEditField.Editable=0;
                    app.NumberofwavelengthsEditField.Value=app.device.nLambda;
                    app.Wavelength1nmEditField.Visible=1;
                    app.Wavelength1nmEditField.Editable=0;
                    app.Wavelength1nmEditField.Value=app.device.Wavelengths(1);
                    app.Wavelength2nmEditField.Visible=1;
                    app.Wavelength2nmEditField.Editable=0;
                    app.Wavelength2nmEditField.Value=app.device.Wavelengths(2);
                    app.Wavelength3nmEditField.Visible=0;
                    app.Wavelength3nmEditField.Editable=0;
                    app.Wavelength4nmEditField.Visible=0;
                    app.Wavelength4nmEditField.Editable=0;
                    app.NumberofauxiliaryportsEditField.Enable=0;
                    app.NumberofauxiliaryportsEditField.Value=app.device.nAux;
                    app.AdjustablesamplingrateCheckBox.Enable=0;
                    app.AdjustablesamplingrateCheckBox.Value=app.device.AdjustableRate;
                    app.AdjustablegainCheckBox.Enable=0;
                    app.AdjustablegainCheckBox.Value=app.device.AdjustableGain;

                    devinfo.levelRepresentation.thresholds

                    app.FilenameforcfgfileEditField.Enable=1;
                    app.DoneButton.Enable=1;

                    for ki=1:8
                        if ki<=app.device.nAux
                            app.(sprintf('Aux%iEditField',ki)).Enable=1;
                        end
                    end

                case ' '

                    app.device.nAux=0;

                    app.NumberofsourcesEditField.Enable=0;
                    app.NumberofdetectorsEditField.Enable=0;
                    app.LaserPowercontrolDropDown.Editable=0;
                    app.SamplingrateEditField.Editable=0;
                    app.NumberofwavelengthsEditField.Editable=0;
                    app.Wavelength1nmEditField.Visible=1;
                    app.Wavelength1nmEditField.Editable=0;
                    app.Wavelength2nmEditField.Visible=1;
                    app.Wavelength2nmEditField.Editable=0;
                    app.Wavelength3nmEditField.Visible=0;
                    app.Wavelength3nmEditField.Editable=0;
                    app.Wavelength4nmEditField.Visible=0;
                    app.Wavelength4nmEditField.Editable=0;
                    app.NumberofauxiliaryportsEditField.Enable=0;
                    app.NumberofauxiliaryportsEditField.Value=app.device.nAux;
                    app.AdjustablesamplingrateCheckBox.Enable=0;
                    app.AdjustablegainCheckBox.Enable=0;


                    app.FilenameforcfgfileEditField.Enable=0;
                    app.DoneButton.Enable=0;

                    for ki=1:8
                        app.(sprintf('Aux%iEditField',ki)).Enable=0;
                    end
            end
            for ki=1:8
                if ki<=app.device.nAux
                    app.(sprintf('Aux%iEditField',ki)).Visible=1;
                else
                    app.(sprintf('Aux%iEditField',ki)).Visible=0;
                end
            end
        end

        % Value changed function: NumberofauxiliaryportsEditField
        function NumberofauxiliaryportsEditFieldValueChanged(app, event)
            value = app.NumberofauxiliaryportsEditField.Value;
            for ki=1:8
                if ki<=value
                    app.(sprintf('Aux%iEditField',ki)).Visible=1;
                    app.(sprintf('Aux%iEditField',ki)).Enable=1;
                else
                    app.(sprintf('Aux%iEditField',ki)).Visible=0;
                    app.(sprintf('Aux%iEditField',ki)).Enable=0;
                end
            end
            app.device.nAux=value;
        end

        % Value changed function: Aux1EditField, Aux2EditField, 
        % ...and 6 other components
        function Aux1EditFieldValueChanged(app, event)

            app.device.auxList=cell(1,app.device.nAux);
            for ki=1:app.device.nAux
                app.device.auxList{ki}=app.(sprintf('Aux%iEditField',ki)).Value;
            end
        end

        % Value changed function: SamplingrateEditField
        function SamplingrateEditFieldValueChanged(app, event)
            value = app.SamplingrateEditField.Value;
            app.device.Rate=value;
        end

        % Value changed function: AdjustablesamplingrateCheckBox
        function AdjustablesamplingrateCheckBoxValueChanged(app, event)
            value = app.AdjustablesamplingrateCheckBox.Value;
            app.device.AdjustableRate=value;
        end

        % Value changed function: AdjustablegainCheckBox
        function AdjustablegainCheckBoxValueChanged(app, event)
            value = app.AdjustablegainCheckBox.Value;
            app.device.AdjustableGain=value;
        end

        % Value changed function: LaserPowercontrolDropDown
        function LaserPowercontrolDropDownValueChanged(app, event)
            value = app.LaserPowercontrolDropDown.Value;
            app.device.LaserPowerControl=value;
        end

        % Value changed function: NumberofdetectorsEditField
        function NumberofdetectorsEditFieldValueChanged(app, event)
            value = app.NumberofdetectorsEditField.Value;
            app.device.nDets=value;
        end

        % Value changed function: NumberofsourcesEditField
        function NumberofsourcesEditFieldValueChanged(app, event)
            value = app.NumberofsourcesEditField.Value;
            app.device.nSrcs=value;
        end

        % Value changed function: NumberofwavelengthsEditField
        function NumberofwavelengthsEditFieldValueChanged(app, event)
            value = app.NumberofwavelengthsEditField.Value;
            app.device.nLambda=value;
        end

        % Value changed function: Wavelength1nmEditField
        function Wavelength1nmEditFieldValueChanged(app, event)
            value = app.Wavelength1nmEditField.Value;
            app.device.Wavelengths(1)=value;
        end

        % Value changed function: Wavelength2nmEditField
        function Wavelength2nmEditFieldValueChanged(app, event)
            value = app.Wavelength2nmEditField.Value;
            app.device.Wavelengths(2)=value;
        end

        % Value changed function: Wavelength3nmEditField
        function Wavelength3nmEditFieldValueChanged(app, event)
            value = app.Wavelength3nmEditField.Value;
            app.device.Wavelengths(3)=value;

        end

        % Value changed function: Wavelength4nmEditField
        function Wavelength4nmEditFieldValueChanged(app, event)
            value = app.Wavelength4nmEditField.Value;
            app.device.Wavelengths(4)=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelecthardwareUIFigure = uifigure('Visible', 'off');
            app.SelecthardwareUIFigure.Name = 'Select hardware';
            app.SelecthardwareUIFigure.CloseRequestFcn = createCallbackFcn(app, @endFcn, true);
            app.DevicetypeLabel = uilabel(app.SelecthardwareUIFigure);
            app.DevicetypeLabel.Text = 'Device type';
            app.DeviceSelection = uidropdown(app.SelecthardwareUIFigure);
            app.DeviceSelection.Items = {};
            app.DeviceSelection.ValueChangedFcn = createCallbackFcn(app, @DeviceSelectionValueChanged, true);
            app.DeviceSelection.Value = {};
            app.SerialportDropDownLabel = uilabel(app.SelecthardwareUIFigure);
            app.SerialportDropDownLabel.Text = 'Serial port';
            app.PortSelection = uidropdown(app.SelecthardwareUIFigure);
            app.PortSelection.Items = {};
            app.PortSelection.ValueChangedFcn = createCallbackFcn(app, @PortSelectionValueChanged, true);
            app.PortSelection.Value = {};
            app.DoneButton = uibutton(app.SelecthardwareUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.NumberofsourcesEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.NumberofsourcesEditFieldLabel.Text = 'Number of sources';
            app.NumberofsourcesEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.NumberofsourcesEditField.Limits = [1 100];
            app.NumberofsourcesEditField.RoundFractionalValues = 'on';
            app.NumberofsourcesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofsourcesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofsourcesEditFieldValueChanged, true);
            app.NumberofsourcesEditField.Enable = 'off';
            app.NumberofsourcesEditField.Tooltip = {'Physical sources. If using dual (source+detector) optodes, each one of them count as one source and one detector'};
            app.NumberofsourcesEditField.Value = 1;
            app.NumberofdetectorsEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.NumberofdetectorsEditFieldLabel.Text = 'Number of detectors';
            app.NumberofdetectorsEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.NumberofdetectorsEditField.Limits = [1 100];
            app.NumberofdetectorsEditField.RoundFractionalValues = 'on';
            app.NumberofdetectorsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofdetectorsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofdetectorsEditFieldValueChanged, true);
            app.NumberofdetectorsEditField.Enable = 'off';
            app.NumberofdetectorsEditField.Tooltip = {'Physical detectors. If using dual (source+detector) optodes, each one of them count as one source and one detector'};
            app.NumberofdetectorsEditField.Value = 1;
            app.LaserPowercontrolDropDownLabel = uilabel(app.SelecthardwareUIFigure);
            app.LaserPowercontrolDropDownLabel.Text = 'Laser Power control';
            app.LaserPowercontrolDropDown = uidropdown(app.SelecthardwareUIFigure);
            app.LaserPowercontrolDropDown.Items = {'None', 'Binary', 'Analog', ''};
            app.LaserPowercontrolDropDown.ValueChangedFcn = createCallbackFcn(app, @LaserPowercontrolDropDownValueChanged, true);
            app.LaserPowercontrolDropDown.Enable = 'off';
            app.LaserPowercontrolDropDown.Value = 'None';
            app.AdjustablegainCheckBox = uicheckbox(app.SelecthardwareUIFigure);
            app.AdjustablegainCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdjustablegainCheckBoxValueChanged, true);
            app.AdjustablegainCheckBox.Enable = 'off';
            app.AdjustablegainCheckBox.Text = 'Adjustable gain?';
            app.AdjustablesamplingrateCheckBox = uicheckbox(app.SelecthardwareUIFigure);
            app.AdjustablesamplingrateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdjustablesamplingrateCheckBoxValueChanged, true);
            app.AdjustablesamplingrateCheckBox.Enable = 'off';
            app.AdjustablesamplingrateCheckBox.Text = 'Adjustable sampling rate?';
            app.SamplingrateEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate';
            app.SamplingrateEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.SamplingrateEditField.Limits = [1 1000];
            app.SamplingrateEditField.RoundFractionalValues = 'on';
            app.SamplingrateEditField.ValueDisplayFormat = '%.0f';
            app.SamplingrateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateEditFieldValueChanged, true);
            app.SamplingrateEditField.Enable = 'off';
            app.SamplingrateEditField.Value = 1;
            app.NumberofwavelengthsEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.NumberofwavelengthsEditFieldLabel.Text = 'Number of wavelengths';
            app.NumberofwavelengthsEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.NumberofwavelengthsEditField.Limits = [1 4];
            app.NumberofwavelengthsEditField.RoundFractionalValues = 'on';
            app.NumberofwavelengthsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofwavelengthsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofwavelengthsEditFieldValueChanged, true);
            app.NumberofwavelengthsEditField.Enable = 'off';
            app.NumberofwavelengthsEditField.Value = 1;
            app.Wavelength1nmEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.Wavelength1nmEditFieldLabel.Text = 'Wavelength 1 (nm)';
            app.Wavelength1nmEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.Wavelength1nmEditField.Limits = [100 10000];
            app.Wavelength1nmEditField.RoundFractionalValues = 'on';
            app.Wavelength1nmEditField.ValueDisplayFormat = '%.0f';
            app.Wavelength1nmEditField.ValueChangedFcn = createCallbackFcn(app, @Wavelength1nmEditFieldValueChanged, true);
            app.Wavelength1nmEditField.Enable = 'off';
            app.Wavelength1nmEditField.Value = 100;
            app.Wavelength2nmEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.Wavelength2nmEditFieldLabel.Text = 'Wavelength 2 (nm)';
            app.Wavelength2nmEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.Wavelength2nmEditField.Limits = [100 10000];
            app.Wavelength2nmEditField.RoundFractionalValues = 'on';
            app.Wavelength2nmEditField.ValueDisplayFormat = '%.0f';
            app.Wavelength2nmEditField.ValueChangedFcn = createCallbackFcn(app, @Wavelength2nmEditFieldValueChanged, true);
            app.Wavelength2nmEditField.Enable = 'off';
            app.Wavelength2nmEditField.Value = 100;
            app.Wavelength3nmEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.Wavelength3nmEditFieldLabel.Visible = 'off';
            app.Wavelength3nmEditFieldLabel.Text = 'Wavelength 3 (nm)';
            app.Wavelength3nmEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.Wavelength3nmEditField.Limits = [100 10000];
            app.Wavelength3nmEditField.RoundFractionalValues = 'on';
            app.Wavelength3nmEditField.ValueDisplayFormat = '%.0f';
            app.Wavelength3nmEditField.ValueChangedFcn = createCallbackFcn(app, @Wavelength3nmEditFieldValueChanged, true);
            app.Wavelength3nmEditField.Enable = 'off';
            app.Wavelength3nmEditField.Visible = 'off';
            app.Wavelength3nmEditField.Value = 100;
            app.Wavelength4nmEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.Wavelength4nmEditFieldLabel.Visible = 'off';
            app.Wavelength4nmEditFieldLabel.Text = 'Wavelength 4 (nm)';
            app.Wavelength4nmEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.Wavelength4nmEditField.Limits = [100 10000];
            app.Wavelength4nmEditField.RoundFractionalValues = 'on';
            app.Wavelength4nmEditField.ValueDisplayFormat = '%.0f';
            app.Wavelength4nmEditField.ValueChangedFcn = createCallbackFcn(app, @Wavelength4nmEditFieldValueChanged, true);
            app.Wavelength4nmEditField.Enable = 'off';
            app.Wavelength4nmEditField.Visible = 'off';
            app.Wavelength4nmEditField.Value = 100;
            app.NumberofauxiliaryportsEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.NumberofauxiliaryportsEditFieldLabel.Text = 'Number of auxiliary ports';
            app.NumberofauxiliaryportsEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.NumberofauxiliaryportsEditField.Limits = [0 8];
            app.NumberofauxiliaryportsEditField.RoundFractionalValues = 'on';
            app.NumberofauxiliaryportsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofauxiliaryportsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofauxiliaryportsEditFieldValueChanged, true);
            app.NumberofauxiliaryportsEditField.Enable = 'off';
            app.AuxiliaryportlabelsPanel = uipanel(app.SelecthardwareUIFigure);
            app.AuxiliaryportlabelsPanel.Title = 'Auxiliary port labels';
            app.Aux1EditFieldLabel = uilabel(app.AuxiliaryportlabelsPanel);
            app.Aux1EditFieldLabel.Text = 'Aux 1';
            app.Aux1EditField = uieditfield(app.AuxiliaryportlabelsPanel, 'text');
            app.Aux1EditField.ValueChangedFcn = createCallbackFcn(app, @Aux1EditFieldValueChanged, true);
            app.Aux1EditField.Enable = 'off';
            app.Aux1EditField.Tooltip = {'Write NONE for unused auxiliary ports'};
            app.Aux1EditField.Value = 'NONE';
            app.Aux2EditFieldLabel = uilabel(app.AuxiliaryportlabelsPanel);
            app.Aux2EditFieldLabel.Text = 'Aux 2';
            app.Aux2EditField = uieditfield(app.AuxiliaryportlabelsPanel, 'text');
            app.Aux2EditField.ValueChangedFcn = createCallbackFcn(app, @Aux1EditFieldValueChanged, true);
            app.Aux2EditField.Enable = 'off';
            app.Aux2EditField.Tooltip = {'Write NONE for unused auxiliary ports'};
            app.Aux2EditField.Value = 'NONE';
            app.Aux3EditFieldLabel = uilabel(app.AuxiliaryportlabelsPanel);
            app.Aux3EditFieldLabel.Text = 'Aux 3';
            app.Aux3EditField = uieditfield(app.AuxiliaryportlabelsPanel, 'text');
            app.Aux3EditField.ValueChangedFcn = createCallbackFcn(app, @Aux1EditFieldValueChanged, true);
            app.Aux3EditField.Enable = 'off';
            app.Aux3EditField.Tooltip = {'Write NONE for unused auxiliary ports'};
            app.Aux3EditField.Value = 'NONE';
            app.Aux4EditFieldLabel = uilabel(app.AuxiliaryportlabelsPanel);
            app.Aux4EditFieldLabel.Text = 'Aux 4';
            app.Aux4EditField = uieditfield(app.AuxiliaryportlabelsPanel, 'text');
            app.Aux4EditField.ValueChangedFcn = createCallbackFcn(app, @Aux1EditFieldValueChanged, true);
            app.Aux4EditField.Enable = 'off';
            app.Aux4EditField.Tooltip = {'Write NONE for unused auxiliary ports'};
            app.Aux4EditField.Value = 'NONE';
            app.Aux5EditFieldLabel = uilabel(app.AuxiliaryportlabelsPanel);
            app.Aux5EditFieldLabel.Text = 'Aux 5';
            app.Aux5EditField = uieditfield(app.AuxiliaryportlabelsPanel, 'text');
            app.Aux5EditField.ValueChangedFcn = createCallbackFcn(app, @Aux1EditFieldValueChanged, true);
            app.Aux5EditField.Enable = 'off';
            app.Aux5EditField.Tooltip = {'Write NONE for unused auxiliary ports'};
            app.Aux5EditField.Value = 'NONE';
            app.Aux6EditFieldLabel = uilabel(app.AuxiliaryportlabelsPanel);
            app.Aux6EditFieldLabel.Text = 'Aux 6';
            app.Aux6EditField = uieditfield(app.AuxiliaryportlabelsPanel, 'text');
            app.Aux6EditField.ValueChangedFcn = createCallbackFcn(app, @Aux1EditFieldValueChanged, true);
            app.Aux6EditField.Enable = 'off';
            app.Aux6EditField.Tooltip = {'Write NONE for unused auxiliary ports'};
            app.Aux6EditField.Value = 'NONE';
            app.Aux7EditFieldLabel = uilabel(app.AuxiliaryportlabelsPanel);
            app.Aux7EditFieldLabel.Text = 'Aux 7';
            app.Aux7EditField = uieditfield(app.AuxiliaryportlabelsPanel, 'text');
            app.Aux7EditField.ValueChangedFcn = createCallbackFcn(app, @Aux1EditFieldValueChanged, true);
            app.Aux7EditField.Enable = 'off';
            app.Aux7EditField.Tooltip = {'Write NONE for unused auxiliary ports'};
            app.Aux7EditField.Value = 'NONE';
            app.Aux8EditFieldLabel = uilabel(app.AuxiliaryportlabelsPanel);
            app.Aux8EditFieldLabel.Text = 'Aux 8';
            app.Aux8EditField = uieditfield(app.AuxiliaryportlabelsPanel, 'text');
            app.Aux8EditField.ValueChangedFcn = createCallbackFcn(app, @Aux1EditFieldValueChanged, true);
            app.Aux8EditField.Enable = 'off';
            app.Aux8EditField.Tooltip = {'Write NONE for unused auxiliary ports'};
            app.Aux8EditField.Value = 'NONE';
            app.FilenameforcfgfileEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.FilenameforcfgfileEditFieldLabel.Text = 'File name for cfg file';
            app.FilenameforcfgfileEditField = uieditfield(app.SelecthardwareUIFigure, 'text');
            app.FilenameforcfgfileEditField.Enable = 'off';
            app.FilenameforcfgfileEditField.Value = 'config';
            app.ThreshlowdBEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.ThreshlowdBEditFieldLabel.Text = 'Thresh low (dB)';
            app.ThreshlowdBEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.ThreshlowdBEditField.Limits = [0 Inf];
            app.ThreshlowdBEditField.Value = 60;
            app.ThreshhighdBEditFieldLabel = uilabel(app.SelecthardwareUIFigure);
            app.ThreshhighdBEditFieldLabel.Text = 'Thresh high (dB)';
            app.ThreshhighdBEditField = uieditfield(app.SelecthardwareUIFigure, 'numeric');
            app.ThreshhighdBEditField.Limits = [0 Inf];
            app.ThreshhighdBEditField.Value = 80;
            % Show the figure after all components are created
            app.SelecthardwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hardwareselect

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelecthardwareUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelecthardwareUIFigure)
        end
    end
end